classdef Predictive_Two_step < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CLPTSADC                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        SingleADCTab                  matlab.ui.container.Tab
        FilteredOutputPanel           matlab.ui.container.Panel
        PlotFFToutf                   matlab.ui.control.CheckBox
        SNDRoutf                      matlab.ui.control.NumericEditField
        SNDRdBLabel_2                 matlab.ui.control.Label
        PlotTimeoutf                  matlab.ui.control.DropDown
        PlotTimeDropDown_2Label       matlab.ui.control.Label
        ENOBoutf                      matlab.ui.control.NumericEditField
        ENOBBitLabel_2                matlab.ui.control.Label
        status                        matlab.ui.control.EditField
        comparison                    matlab.ui.control.CheckBox
        UBCfig                        matlab.ui.control.Image
        RunSimulation                 matlab.ui.control.Button
        OutputPanel                   matlab.ui.container.Panel
        Out_type                      matlab.ui.control.DropDown
        OutTypeLabel                  matlab.ui.control.Label
        ENOB_t_out                    matlab.ui.control.NumericEditField
        ENOB_tBitLabel                matlab.ui.control.Label
        PlotFFTout                    matlab.ui.control.CheckBox
        SNDRout                       matlab.ui.control.NumericEditField
        SNDRdBLabel                   matlab.ui.control.Label
        PlotTimeout                   matlab.ui.control.DropDown
        PlotTimeDropDownLabel         matlab.ui.control.Label
        ENOBout                       matlab.ui.control.NumericEditField
        ENOBBitLabel                  matlab.ui.control.Label
        ADCfig                        matlab.ui.control.Image
        DecimatorPanel                matlab.ui.container.Panel
        OrderLPF                      matlab.ui.control.NumericEditField
        OrderLabel                    matlab.ui.control.Label
        LPFType                       matlab.ui.control.DropDown
        TypeDropDown_3Label           matlab.ui.control.Label
        BW                            matlab.ui.control.NumericEditField
        BWLabel                       matlab.ui.control.Label
        CreateFilterButton            matlab.ui.control.Button
        FilterSpecsPanel              matlab.ui.container.Panel
        Term2                         matlab.ui.control.NumericEditField
        Term2Label                    matlab.ui.control.Label
        Term3                         matlab.ui.control.NumericEditField
        Term3Label                    matlab.ui.control.Label
        DACPanel                      matlab.ui.container.Panel
        DoC                           matlab.ui.control.NumericEditField
        DoCLabel                      matlab.ui.control.Label
        M0                            matlab.ui.control.NumericEditField
        M0BitLabel                    matlab.ui.control.Label
        DACres                        matlab.ui.control.NumericEditField
        ResolutionBitLabel_2          matlab.ui.control.Label
        DACINL                        matlab.ui.control.NumericEditField
        DACPlot                       matlab.ui.control.CheckBox
        Max_INLLSBLabel_2             matlab.ui.control.Label
        PredictorPanel                matlab.ui.container.Panel
        PredOrder                     matlab.ui.control.NumericEditField
        OrderLabel_2                  matlab.ui.control.Label
        Angle                         matlab.ui.control.NumericEditField
        AngleLabel                    matlab.ui.control.Label
        AngleRatio                    matlab.ui.control.NumericEditField
        AngleratioLabel               matlab.ui.control.Label
        Beta                          matlab.ui.control.NumericEditField
        PoleLabel                     matlab.ui.control.Label
        PredType                      matlab.ui.control.DropDown
        TypeLabel                     matlab.ui.control.Label
        Alpha                         matlab.ui.control.NumericEditField
        ZeroLabel                     matlab.ui.control.Label
        PredPlot                      matlab.ui.control.CheckBox
        ADCPanel                      matlab.ui.container.Panel
        ADCres                        matlab.ui.control.NumericEditField
        ResolutionBitLabel            matlab.ui.control.Label
        ADCINL                        matlab.ui.control.NumericEditField
        ADCPlot                       matlab.ui.control.CheckBox
        Max_INLLSBLabel               matlab.ui.control.Label
        SaturationPanel               matlab.ui.container.Panel
        PlotDigital                   matlab.ui.control.CheckBox
        LimitAnalog                   matlab.ui.control.NumericEditField
        LimitAnalogLabel              matlab.ui.control.Label
        LimitDigital                  matlab.ui.control.NumericEditField
        PlotAnalog                    matlab.ui.control.CheckBox
        LimitDigitalLabel             matlab.ui.control.Label
        AmplifierPanel                matlab.ui.container.Panel
        AmpPlot_FFT                   matlab.ui.control.CheckBox
        Gain                          matlab.ui.control.NumericEditField
        GainLabel                     matlab.ui.control.Label
        NonlinearityAmp               matlab.ui.control.NumericEditField
        AmpPlot                       matlab.ui.control.CheckBox
        NonlinearityAMPLabel          matlab.ui.control.Label
        SampleHold                    matlab.ui.container.Panel
        Fs                            matlab.ui.control.NumericEditField
        FsLabel                       matlab.ui.control.Label
        R                             matlab.ui.control.NumericEditField
        Repeatition2Label             matlab.ui.control.Label
        NFFT                          matlab.ui.control.NumericEditField
        ofFFTpts2Label                matlab.ui.control.Label
        OSR                           matlab.ui.control.NumericEditField
        SHPlot                        matlab.ui.control.CheckBox
        OSRLabel                      matlab.ui.control.Label
        Input                         matlab.ui.container.Panel
        InputPlot                     matlab.ui.control.CheckBox
        InputFreq                     matlab.ui.control.NumericEditField
        FreqLabel_2                   matlab.ui.control.Label
        InputType                     matlab.ui.control.DropDown
        TypeDropDownLabel             matlab.ui.control.Label
        Amplitude                     matlab.ui.control.NumericEditField
        AmplitudeLabel                matlab.ui.control.Label
        AmpSweep                      matlab.ui.control.CheckBox
        PZ                            matlab.ui.control.UIAxes
        Loop                          matlab.ui.control.UIAxes
        H                             matlab.ui.control.UIAxes
        PipelineADCTab                matlab.ui.container.Tab
        Fig_Plot_type                 matlab.ui.control.DropDown
        FilterLabel_2                 matlab.ui.control.Label
        Fig_Plot_Stage                matlab.ui.control.DropDown
        StageLabel_2                  matlab.ui.control.Label
        Filter_Plot_type              matlab.ui.control.DropDown
        FilterLabel                   matlab.ui.control.Label
        ForwardandInverseFilterPanel  matlab.ui.container.Panel
        FF_Lim_S1                     matlab.ui.control.NumericEditField
        LimitLabel                    matlab.ui.control.Label
        FF_Lim_S2                     matlab.ui.control.NumericEditField
        Est_IF_S2                     matlab.ui.control.CheckBox
        Est_IF_S1                     matlab.ui.control.CheckBox
        Est_IFLabel                   matlab.ui.control.Label
        Est_FF_S2                     matlab.ui.control.CheckBox
        Est_FF_S1                     matlab.ui.control.CheckBox
        Est_FFLabel                   matlab.ui.control.Label
        FF_BW_S2                      matlab.ui.control.NumericEditField
        FF_BW_S1                      matlab.ui.control.NumericEditField
        BWLabel_3                     matlab.ui.control.Label
        FF_Order_S2                   matlab.ui.control.NumericEditField
        FF_Order_S1                   matlab.ui.control.NumericEditField
        OrderLabel_6                  matlab.ui.control.Label
        FF_Type                       matlab.ui.control.DropDown
        TypeDropDown_3Label_3         matlab.ui.control.Label
        Filter_Plot_Stage             matlab.ui.control.DropDown
        StageLabel                    matlab.ui.control.Label
        DACPanel_3                    matlab.ui.container.Panel
        DoCLabel_3                    matlab.ui.control.Label
        DoC_S1                        matlab.ui.control.NumericEditField
        DoC_S3                        matlab.ui.control.NumericEditField
        DoC_S2                        matlab.ui.control.NumericEditField
        M0BitLabel_3                  matlab.ui.control.Label
        M0_S1                         matlab.ui.control.NumericEditField
        M0_S3                         matlab.ui.control.NumericEditField
        M0_S2                         matlab.ui.control.NumericEditField
        S1S2S3Label_5                 matlab.ui.control.Label
        DACPlot_S3                    matlab.ui.control.CheckBox
        DACPlot_S2                    matlab.ui.control.CheckBox
        DACPlot_S1                    matlab.ui.control.CheckBox
        PlotLabel_4                   matlab.ui.control.Label
        DACINL_S3                     matlab.ui.control.NumericEditField
        DACINL_S2                     matlab.ui.control.NumericEditField
        DACINL_S1                     matlab.ui.control.NumericEditField
        INLLSBLabel_2                 matlab.ui.control.Label
        ResBitLabel_2                 matlab.ui.control.Label
        DACRes_S1                     matlab.ui.control.NumericEditField
        DACRes_S3                     matlab.ui.control.NumericEditField
        DACRes_S2                     matlab.ui.control.NumericEditField
        ADCPanel_3                    matlab.ui.container.Panel
        Ofs_ErrLabel                  matlab.ui.control.Label
        ADC_OfsErr_S1                 matlab.ui.control.NumericEditField
        ADC_OfsErr_S3                 matlab.ui.control.NumericEditField
        ADC_OfsErr_S2                 matlab.ui.control.NumericEditField
        G_ErrLabel                    matlab.ui.control.Label
        ADCG_Err_S1                   matlab.ui.control.NumericEditField
        ADCG_Err_S3                   matlab.ui.control.NumericEditField
        ADCG_Err_S2                   matlab.ui.control.NumericEditField
        S1S2S3Label_4                 matlab.ui.control.Label
        ADCPlot_S3                    matlab.ui.control.CheckBox
        ADCPlot_S2                    matlab.ui.control.CheckBox
        ADCPlot_S1                    matlab.ui.control.CheckBox
        PlotLabel_3                   matlab.ui.control.Label
        ADCINL_S3                     matlab.ui.control.NumericEditField
        ADCINL_S2                     matlab.ui.control.NumericEditField
        ADCINL_S1                     matlab.ui.control.NumericEditField
        INLLSBLabel                   matlab.ui.control.Label
        ResBitLabel                   matlab.ui.control.Label
        ADCRes_S1                     matlab.ui.control.NumericEditField
        ADCRes_S3                     matlab.ui.control.NumericEditField
        ADCRes_S2                     matlab.ui.control.NumericEditField
        SaturationPanel_2             matlab.ui.container.Panel
        S1S2S3Label_3                 matlab.ui.control.Label
        SATPlot_S3                    matlab.ui.control.CheckBox
        SATPlot_S2                    matlab.ui.control.CheckBox
        SATPlot_S1                    matlab.ui.control.CheckBox
        PlotLabel_2                   matlab.ui.control.Label
        DigSAT_S3                     matlab.ui.control.NumericEditField
        DigSAT_S2                     matlab.ui.control.NumericEditField
        DigSAT_S1                     matlab.ui.control.NumericEditField
        D_LimLabel                    matlab.ui.control.Label
        A_LimLabel                    matlab.ui.control.Label
        AnaSAT_S1                     matlab.ui.control.NumericEditField
        AnaSAT_S3                     matlab.ui.control.NumericEditField
        AnaSAT_S2                     matlab.ui.control.NumericEditField
        Input_3                       matlab.ui.container.Panel
        Pipe_Method                   matlab.ui.control.DropDown
        MethodLabel                   matlab.ui.control.Label
        IF_S2                         matlab.ui.control.CheckBox
        IF_S1                         matlab.ui.control.CheckBox
        IFLabel                       matlab.ui.control.Label
        FF_S2                         matlab.ui.control.CheckBox
        FFLabel                       matlab.ui.control.Label
        FF_S1                         matlab.ui.control.CheckBox
        Compensate_S2                 matlab.ui.control.CheckBox
        CompensLabel                  matlab.ui.control.Label
        Compensate_S1                 matlab.ui.control.CheckBox
        S1S2S3Label                   matlab.ui.control.Label
        CLPTS_S3                      matlab.ui.control.CheckBox
        CLPTS_S2                      matlab.ui.control.CheckBox
        CLPTSLabel                    matlab.ui.control.Label
        CLPTS_S1                      matlab.ui.control.CheckBox
        Num_Stage                     matlab.ui.control.DropDown
        ofstagesLabel                 matlab.ui.control.Label
        FilteredOutputPanel_2         matlab.ui.container.Panel
        PlotFFToutf_2                 matlab.ui.control.CheckBox
        SNDRoutf_2                    matlab.ui.control.NumericEditField
        SNDRdBLabel_4                 matlab.ui.control.Label
        PlotTimeoutf_2                matlab.ui.control.DropDown
        PlotTimeDropDown_2Label_2     matlab.ui.control.Label
        ENOBoutf_2                    matlab.ui.control.NumericEditField
        ENOBBitLabel_4                matlab.ui.control.Label
        status_2                      matlab.ui.control.EditField
        comparison_2                  matlab.ui.control.CheckBox
        RunSimulation_2               matlab.ui.control.Button
        OutputPanel_2                 matlab.ui.container.Panel
        ENOB_t_out_2                  matlab.ui.control.NumericEditField
        ENOB_tBitLabel_2              matlab.ui.control.Label
        PlotFFTout_2                  matlab.ui.control.CheckBox
        SNDRout_2                     matlab.ui.control.NumericEditField
        SNDRdBLabel_3                 matlab.ui.control.Label
        PlotTimeout_2                 matlab.ui.control.DropDown
        PlotTimeDropDownLabel_2       matlab.ui.control.Label
        ENOBout_2                     matlab.ui.control.NumericEditField
        ENOBBitLabel_3                matlab.ui.control.Label
        ADCfig_2                      matlab.ui.control.Image
        DecimatorPanel_2              matlab.ui.container.Panel
        OrderLPF_2                    matlab.ui.control.NumericEditField
        OrderLabel_4                  matlab.ui.control.Label
        LPFType_2                     matlab.ui.control.DropDown
        TypeDropDown_3Label_2         matlab.ui.control.Label
        BW_2                          matlab.ui.control.NumericEditField
        BWLabel_2                     matlab.ui.control.Label
        CreateFilterButton_2          matlab.ui.control.Button
        FilterSpecsPanel_2            matlab.ui.container.Panel
        Term3Label_2                  matlab.ui.control.Label
        Term3_S1                      matlab.ui.control.NumericEditField
        Term3_S3                      matlab.ui.control.NumericEditField
        Term3_S2                      matlab.ui.control.NumericEditField
        Term2Label_2                  matlab.ui.control.Label
        Term2_S1                      matlab.ui.control.NumericEditField
        Term2_S3                      matlab.ui.control.NumericEditField
        Term2_S2                      matlab.ui.control.NumericEditField
        PredictorPanel_2              matlab.ui.container.Panel
        Pred_Plot_S3                  matlab.ui.control.CheckBox
        Pred_Plot_S2                  matlab.ui.control.CheckBox
        Pred_Plot_S1                  matlab.ui.control.CheckBox
        PlotLabel_5                   matlab.ui.control.Label
        S1S2S3Label_6                 matlab.ui.control.Label
        AngleLabel_2                  matlab.ui.control.Label
        Angle_S1                      matlab.ui.control.NumericEditField
        Angle_S3                      matlab.ui.control.NumericEditField
        Angle_S2                      matlab.ui.control.NumericEditField
        RatioLabel                    matlab.ui.control.Label
        Ang_Ratio_S1                  matlab.ui.control.NumericEditField
        Ang_Ratio_S3                  matlab.ui.control.NumericEditField
        Ang_Ratio_S2                  matlab.ui.control.NumericEditField
        OrderLabel_5                  matlab.ui.control.Label
        Pred_Order_S1                 matlab.ui.control.NumericEditField
        Pred_Order_S3                 matlab.ui.control.NumericEditField
        Pred_Order_S2                 matlab.ui.control.NumericEditField
        Zero_S3                       matlab.ui.control.NumericEditField
        Zero_S2                       matlab.ui.control.NumericEditField
        Zero_S1                       matlab.ui.control.NumericEditField
        ZeroLabel_2                   matlab.ui.control.Label
        PoleLabel_2                   matlab.ui.control.Label
        Pole_S1                       matlab.ui.control.NumericEditField
        Pole_S3                       matlab.ui.control.NumericEditField
        Pole_S2                       matlab.ui.control.NumericEditField
        PredType_2                    matlab.ui.control.DropDown
        TypeLabel_2                   matlab.ui.control.Label
        AmplifierPanel_2              matlab.ui.container.Panel
        S1S2S3Label_2                 matlab.ui.control.Label
        AmpPlot_S3                    matlab.ui.control.CheckBox
        AmpPlot_S2                    matlab.ui.control.CheckBox
        AmpPlot_S1                    matlab.ui.control.CheckBox
        PlotLabel                     matlab.ui.control.Label
        AmpFFT_S3                     matlab.ui.control.CheckBox
        AmpFFT_S2                     matlab.ui.control.CheckBox
        AmpFFT_S1                     matlab.ui.control.CheckBox
        FFTLabel                      matlab.ui.control.Label
        NL_S3                         matlab.ui.control.NumericEditField
        NL_S2                         matlab.ui.control.NumericEditField
        NL_S1                         matlab.ui.control.NumericEditField
        NLLabel                       matlab.ui.control.Label
        GainLabel_2                   matlab.ui.control.Label
        Gain_S1                       matlab.ui.control.NumericEditField
        Gain_S3                       matlab.ui.control.NumericEditField
        Gain_S2                       matlab.ui.control.NumericEditField
        SampleHold_2                  matlab.ui.container.Panel
        Fs_2                          matlab.ui.control.NumericEditField
        FsLabel_2                     matlab.ui.control.Label
        R_2                           matlab.ui.control.NumericEditField
        Repeatition2Label_2           matlab.ui.control.Label
        NFFT_2                        matlab.ui.control.NumericEditField
        ofFFTpts2Label_2              matlab.ui.control.Label
        OSR_2                         matlab.ui.control.NumericEditField
        SHPlot_2                      matlab.ui.control.CheckBox
        OSRLabel_2                    matlab.ui.control.Label
        Input_2                       matlab.ui.container.Panel
        InputPlot_2                   matlab.ui.control.CheckBox
        InputFreq_2                   matlab.ui.control.NumericEditField
        FreqLabel_3                   matlab.ui.control.Label
        InputType_2                   matlab.ui.control.DropDown
        TypeDropDownLabel_2           matlab.ui.control.Label
        Amplitude_2                   matlab.ui.control.NumericEditField
        AmplitudeLabel_2              matlab.ui.control.Label
        AmpSweep_2                    matlab.ui.control.CheckBox
        Pred_2                        matlab.ui.control.UIAxes
        FF_IF_2                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        
            app.Fs.Value = 0;
            app.BW.Value = 0;
            app.SNDRout.Value = 0;
            app.ENOBout.Value = 0;
            app.SNDRoutf.Value = 0;
            app.ENOBoutf.Value = 0;
            app.ENOB_t_out.Value = 0;
            app.LimitDigital.Value = 0;
            app.status.Value = ' Ready !';
            app.Term2.Value = 0;
            app.Term3.Value = 0;
            app.M0.Value = 0;
            app.DoC.Value = 0;
            
            app.Alpha.Value = 1;
            app.Beta.Value = 0;
            app.PredOrder.Value = 2;
            app.PredType.Value = 'Real pole-Real zero';
            app.AngleRatio.Value = 1;
            app.OSR.Value = 4;
            app.Gain.Value = 8;
            app.OrderLPF.Value = 4;
            app.LPFType.Value = 'Butterworth';
            
            app.Amplitude.Value = 0.9;
            app.Amplitude.BackgroundColor = [1 1 1];
            app.InputType.Value = 'Sine';
            app.InputFreq.Value = 1;
            app.NFFT.Value = 8;
            app.R.Value = 4;
            app.NonlinearityAmp.Value = 0;
            app.LimitAnalog.Value = 1;
            app.ADCINL.Value = 0;
            app.ADCres.Value = 9;
            app.DACINL.Value = 0;
            app.DACres.Value = 5;
          %%%%%%%%%%%%%%%%%%%%%%%% 
          %% Tab 2
          
            app.Num_Stage.Value = '1';
            app.Pipe_Method.Value = 'Custome';
            app.CLPTS_S2.Visible ='off';
            app.CLPTS_S3.Visible ='off';
            app.Compensate_S1.Visible ='off';
            app.Compensate_S2.Visible ='off';
            app.FF_S1.Visible ='off';
            app.FF_S2.Visible ='off';
            app.IF_S1.Visible ='off';
            app.IF_S2.Visible ='off';
            
            app.Gain_S2.Visible = 'off';
            app.Gain_S3.Visible = 'off';
            app.NL_S2.Visible = 'off';
            app.NL_S3.Visible = 'off';
            app.AmpFFT_S2.Visible ='off';
            app.AmpFFT_S3.Visible ='off';
            app.AmpPlot_S2.Visible ='off';
            app.AmpPlot_S3.Visible ='off';
            
            app.AnaSAT_S2.Visible ='off';
            app.AnaSAT_S3.Visible ='off';
            app.DigSAT_S2.Visible ='off';
            app.DigSAT_S3.Visible ='off';
            app.SATPlot_S2.Visible ='off';
            app.SATPlot_S3.Visible ='off';
            
            app.ADCRes_S2.Visible = 'off';
            app.ADCRes_S3.Visible = 'off';
            app.ADCINL_S2.Visible = 'off';
            app.ADCINL_S3.Visible = 'off';
            app.ADCG_Err_S2.Visible = 'off';
            app.ADCG_Err_S3.Visible = 'off';
            app.ADC_OfsErr_S2.Visible = 'off';
            app.ADC_OfsErr_S3.Visible = 'off';
            app.ADCPlot_S2.Visible = 'off';
            app.ADCPlot_S3.Visible = 'off';
            
            app.DACRes_S2.Visible = 'off';
            app.DACRes_S3.Visible = 'off';
            app.DACINL_S2.Visible = 'off';
            app.DACINL_S3.Visible = 'off';
            app.M0_S2.Visible = 'off';
            app.M0_S3.Visible = 'off';
            app.DoC_S2.Visible = 'off';
            app.DoC_S3.Visible = 'off';
            app.DACPlot_S2.Visible = 'off';
            app.DACPlot_S3.Visible = 'off';
            
            app.FF_Type.Enable = 'off';
            app.FF_Order_S1.Enable = 'off';
            app.FF_Order_S2.Enable = 'off';
            app.FF_BW_S1.Enable = 'off';
            app.FF_BW_S2.Enable = 'off';
            app.FF_Lim_S1.Enable = 'off';
            app.FF_Lim_S2.Enable = 'off';
            app.Est_FF_S1.Enable = 'off';
            app.Est_FF_S2.Enable = 'off';
            app.Est_IF_S1.Enable = 'off';
            app.Est_IF_S2.Enable = 'off';
            
            app.Pole_S2.Visible = 'off';
            app.Pole_S3.Visible = 'off';
            app.Zero_S2.Visible = 'off';
            app.Zero_S3.Visible = 'off';
            app.Pred_Order_S2.Visible = 'off';
            app.Pred_Order_S3.Visible = 'off';
            app.Ang_Ratio_S2.Visible = 'off';
            app.Ang_Ratio_S3.Visible = 'off';
            app.Angle_S2.Visible = 'off';
            app.Angle_S3.Visible = 'off';
            
            app.Term2_S2.Visible = 'off';
            app.Term2_S3.Visible = 'off';
            app.Term3_S2.Visible = 'off';
            app.Term3_S3.Visible = 'off';
            
        
        end

        % Button pushed function: CreateFilterButton
        function CreateFilterButtonPushed(app, event)

            app.status.Value = 'Filter is being created';
            
            pause(1)
            
            alpha = app.Alpha.Value;
            beta = app.Beta.Value;
            Order = app.PredOrder.Value;
            Pred_Type = app.PredType.Value;
            angle_ratio = app.AngleRatio.Value;
            OSR1 = app.OSR.Value;
            OSR1 = 2^OSR1;
            
            Predictor_feature = [alpha,beta,Order,angle_ratio,OSR1];
            k = app.Gain.Value;
            
            
            % Decimator
            LPF_order = app.OrderLPF.Value;
            LPF_Type = app.LPFType.Value;


            if strcmp(Pred_Type,'Real pole-Real zero')
                P_Type = 1;
            elseif strcmp(Pred_Type,'Real pole-Complex zero')
                P_Type = 2;
            elseif strcmp(Pred_Type,'Complex pole-Complex zero')
                P_Type = 3;    
            end
            
            [angle,H_N,H_D,LPF_N,LPF_D,Resp_Loop,freq_Loop,Resp_H,freq_H,h_LPF,w_LPF,Term] = ...
            PredictorFilter(k,Predictor_feature,P_Type,LPF_order,LPF_Type);
           %[angle,H_N,H_D,LPF_N,LPF_D,Resp_Loop,freq_Loop,Resp_H,freq_H,h_LPF,w_LPF,Term]
            app.Angle.Value = angle;
            plot(app.H,freq_H,abs(Resp_H))

            plot(freq_H,abs(Resp_H))
            xlabel("freq")
            ylabel("gain")
            title("predictor")

            plot(app.Loop,freq_Loop,abs(Resp_Loop))

            figure
            plot(freq_Loop,abs(Resp_Loop))
            xlabel("freq")
            ylabel("gain")
            title("CLF")

            plot(app.H,freq_H,abs(Resp_H))

            plot(app.PZ,w_LPF,abs(h_LPF))
%           

            app.Term2.Value = Term(1);
            app.Term3.Value = Term(2);
            app.M0.Value = abs(Term(3));
            app.DoC.Value = abs(Term(4));
            app.status.Value = 'Filter realization completed !';
            
            pause(1)
            
        end

        % Button pushed function: RunSimulation
        function RunSimulationButtonPushed(app, event)
        
            app.status.Value = 'Filter is being created';
            
            app.Amplitude.BackgroundColor = [1 1 1];
            app.Fs.Value = 0;
            app.BW.Value = 0;
            app.SNDRout.Value = 0;
            app.ENOBout.Value = 0;
            app.SNDRoutf.Value = 0;
            app.ENOBoutf.Value = 0;
            app.ENOB_t_out.Value = 0;
            app.LimitDigital.Value = 0;
            app.Term2.Value = 0;
            app.Term3.Value = 0;
            app.M0.Value = 0;
            app.DoC.Value = 0;
            
            pause(1)
            
            alpha = app.Alpha.Value;
            beta = app.Beta.Value;
            Order = app.PredOrder.Value;
            Pred_Type = app.PredType.Value;
            angle_ratio = app.AngleRatio.Value;
            OSR1 = app.OSR.Value;
            OSR1 = 2^OSR1;
            
            Predictor_feature = [alpha,beta,Order,angle_ratio,OSR1];
            k = app.Gain.Value;
            
            
            % Decimator
            LPF_order = app.OrderLPF.Value;
            LPF_Type = app.LPFType.Value;


            if strcmp(Pred_Type,'Real pole-Real zero')
                P_Type = 1;
            elseif strcmp(Pred_Type,'Real pole-Complex zero')
                P_Type = 2;
            elseif strcmp(Pred_Type,'Complex pole-Complex zero')
                P_Type = 3;    
            end
            
            [angle,H_N,H_D,LPF_N,LPF_D,Resp_Loop,freq_Loop,Resp_H,freq_H,h_LPF,w_LPF,Term] = ...
            PredictorFilter(k,Predictor_feature,P_Type,LPF_order,LPF_Type);
            
            app.Angle.Value = angle;
            plot(app.H,freq_H,abs(Resp_H))
            plot(app.Loop,freq_Loop,abs(Resp_Loop))
            plot(app.PZ,w_LPF,abs(h_LPF))
%           

            app.Term2.Value = Term(1);
            app.Term3.Value = Term(2);
            app.M0.Value = abs(Term(3));
            app.DoC.Value = abs(Term(4));
            app.status.Value = 'Filter realization completed !';
            
            pause(1)
            
            Plot_status = {};
            Plot_status{1} = app.PredPlot.Value;
            
            app.status.Value = 'Simulation is running';
            
            pause(1)
            
            % Input Signal
            Sweep = app.AmpSweep.Value;
            Amp = app.Amplitude.Value;
            In_Type = app.InputType.Value;
            Fin = app.InputFreq.Value;
            Plot_status{2} = app.InputPlot.Value;
            Input_feature = {Sweep,Amp,Fin};
            
            % Sample and Hold
            Plot_status{3} = app.SHPlot.Value;
            
            NFFT1 = app.NFFT.Value;
            NFFT1 = 2^NFFT1;
            R1 = app.R.Value;
            R1 = 2^R1;
            SH_feature = [OSR1,NFFT1,R1];
            
            % Amplifier
            Plot_status{4} = app.AmpPlot.Value;
            Plot_status{13} = app.AmpPlot_FFT.Value;
            Amp_NL = app.NonlinearityAmp.Value;
            Amplifier_feature = [k,Amp_NL];
            
            % Saturation Blocks
            Plot_status{5} = app.PlotAnalog.Value;
            Plot_status{6} = app.PlotDigital.Value;
            LimAna = app.LimitAnalog.Value;
            SAT = LimAna;
            
            % ADC
            Plot_status{7} = app.ADCPlot.Value;
            INL_ADC = app.ADCINL.Value;
            ADC_res = app.ADCres.Value;
            ADC_feature = [ADC_res,INL_ADC];
            
            % DAC
            Plot_status{8} = app.DACPlot.Value;
            INL_DAC = app.DACINL.Value;
            DAC_res = app.DACres.Value;
            DAC_feature = [DAC_res,INL_DAC];
            
            
            % Out
            Plot_status{9} = app.PlotTimeout.Value;
            Plot_status{10} = app.PlotFFTout.Value;
            
            % Out Filtered
            Plot_status{11} = app.PlotTimeoutf.Value;
            Plot_status{12} = app.PlotFFToutf.Value; 
            
            Plot_status{14} = app.comparison.Value;
            
            OT = app.Out_type.Value;
            
            if app.AmpSweep.Value
                
            app.Amplitude.BackgroundColor = [0.65 0.65 0.65];
            app.Amplitude.Value = 0;
            pause(1)

            
            [Amp_m,fs,BW0,SNDR,ENOB,SNDR_f,ENOB_f,SNDR_t,ENOB_t,SAT_Dig] = CLPTS_ADC_Sweep(Plot_status,...
            DAC_feature, ADC_feature, SAT, Amplifier_feature,...
            SH_feature, Input_feature,In_Type,H_N,H_D,LPF_N,LPF_D);
            

            app.Amplitude.Value = Amp_m;
            
            else
                
            [fs,BW0,SNDR,ENOB,SNDR_f,ENOB_f,SNDR_t,ENOB_t,SAT_Dig] = CLPTS_ADC(Plot_status,...
            DAC_feature, ADC_feature, SAT, Amplifier_feature,...
            SH_feature, Input_feature,In_Type,H_N,H_D,LPF_N,LPF_D,OT);
            
            end
            
            app.Fs.Value = fs;
            app.BW.Value = BW0;
            app.SNDRout.Value = SNDR;
            app.ENOBout.Value = ENOB;
            app.SNDRoutf.Value = SNDR_f;
            app.ENOBoutf.Value = ENOB_f;
            app.ENOB_t_out.Value = ENOB_t;
            app.LimitDigital.Value = SAT_Dig;
            app.status.Value = 'Simulation completed !';

            
        end

        % Value changed function: Num_Stage
        function Num_StageValueChanged(app, event)
            value = app.Num_Stage.Value;
            if value == '1'
                app.CLPTS_S2.Visible ='off';
            app.CLPTS_S3.Visible ='off';
            app.Compensate_S1.Visible ='off';
            app.Compensate_S2.Visible ='off';
            app.FF_S1.Visible ='off';
            app.FF_S2.Visible ='off';
            app.IF_S1.Visible ='off';
            app.IF_S2.Visible ='off';
            
            app.Gain_S2.Visible = 'off';
            app.Gain_S3.Visible = 'off';
            app.NL_S2.Visible = 'off';
            app.NL_S3.Visible = 'off';
            app.AmpFFT_S2.Visible ='off';
            app.AmpFFT_S3.Visible ='off';
            app.AmpPlot_S2.Visible ='off';
            app.AmpPlot_S3.Visible ='off';
            
            app.AnaSAT_S2.Visible ='off';
            app.AnaSAT_S3.Visible ='off';
            app.DigSAT_S2.Visible ='off';
            app.DigSAT_S2.Visible ='off';
            app.SATPlot_S2.Visible ='off';
            app.SATPlot_S3.Visible ='off';
            
            app.ADCRes_S2.Visible = 'off';
            app.ADCRes_S3.Visible = 'off';
            app.ADCINL_S2.Visible = 'off';
            app.ADCINL_S3.Visible = 'off';
            app.ADCG_Err_S2.Visible = 'off';
            app.ADCG_Err_S3.Visible = 'off';
            app.ADC_OfsErr_S2.Visible = 'off';
            app.ADC_OfsErr_S3.Visible = 'off';
            app.ADCPlot_S2.Visible = 'off';
            app.ADCPlot_S3.Visible = 'off';
            
            app.DACRes_S2.Visible = 'off';
            app.DACRes_S3.Visible = 'off';
            app.DACINL_S2.Visible = 'off';
            app.DACINL_S3.Visible = 'off';
            app.M0_S2.Visible = 'off';
            app.M0_S3.Visible = 'off';
            app.DoC_S2.Visible = 'off';
            app.DoC_S3.Visible = 'off';
            app.DACPlot_S2.Visible = 'off';
            app.DACPlot_S3.Visible = 'off';
            
            app.FF_Type.Enable = 'off';
            app.FF_Order_S1.Enable = 'off';
            app.FF_Order_S2.Enable = 'off';
            app.FF_BW_S1.Enable = 'off';
            app.FF_BW_S2.Enable = 'off';
            app.FF_Lim_S1.Enable = 'off';
            app.FF_Lim_S2.Enable = 'off';
            app.Est_FF_S1.Enable = 'off';
            app.Est_FF_S2.Enable = 'off';
            app.Est_IF_S1.Enable = 'off';
            app.Est_IF_S2.Enable = 'off';
            
            app.Pole_S2.Visible = 'off';
            app.Pole_S3.Visible = 'off';
            app.Zero_S2.Visible = 'off';
            app.Zero_S3.Visible = 'off';
            app.Pred_Order_S2.Visible = 'off';
            app.Pred_Order_S3.Visible = 'off';
            app.Ang_Ratio_S2.Visible = 'off';
            app.Ang_Ratio_S3.Visible = 'off';
            app.Angle_S2.Visible = 'off';
            app.Angle_S3.Visible = 'off';
            
            app.Term2_S2.Visible = 'off';
            app.Term2_S3.Visible = 'off';
            app.Term3_S2.Visible = 'off';
            app.Term3_S2.Visible = 'off';
            
            elseif value == '2'
                
            app.CLPTS_S2.Visible ='on';
            app.CLPTS_S3.Visible ='off';
            app.Compensate_S1.Visible ='on';
            app.Compensate_S2.Visible ='off';
            app.FF_S1.Visible ='on';
            app.FF_S2.Visible ='off';
            app.IF_S1.Visible ='on';
            app.IF_S2.Visible ='off';
            
            app.Gain_S2.Visible = 'on';
            app.Gain_S3.Visible = 'off';
            app.NL_S2.Visible = 'on';
            app.NL_S3.Visible = 'off';
            app.AmpFFT_S2.Visible ='on';
            app.AmpFFT_S3.Visible ='off';
            app.AmpPlot_S2.Visible ='on';
            app.AmpPlot_S3.Visible ='off';
            
            app.AnaSAT_S2.Visible ='on';
            app.AnaSAT_S3.Visible ='off';
            app.DigSAT_S2.Visible ='on';
            app.DigSAT_S3.Visible ='off';
            app.SATPlot_S2.Visible ='on';
            app.SATPlot_S3.Visible ='off';
            
            app.ADCRes_S2.Visible = 'on';
            app.ADCRes_S3.Visible = 'off';
            app.ADCINL_S2.Visible = 'on';
            app.ADCINL_S3.Visible = 'off';
            app.ADCG_Err_S2.Visible = 'on';
            app.ADCG_Err_S3.Visible = 'off';
            app.ADC_OfsErr_S2.Visible = 'on';
            app.ADC_OfsErr_S3.Visible = 'off';
            app.ADCPlot_S2.Visible = 'on';
            app.ADCPlot_S3.Visible = 'off';
            
            app.DACRes_S2.Visible = 'on';
            app.DACRes_S3.Visible = 'off';
            app.DACINL_S2.Visible = 'on';
            app.DACINL_S3.Visible = 'off';
            app.M0_S2.Visible = 'on';
            app.M0_S3.Visible = 'off';
            app.DoC_S2.Visible = 'on';
            app.DoC_S3.Visible = 'off';
            app.DACPlot_S2.Visible = 'on';
            app.DACPlot_S3.Visible = 'off';
            
            app.FF_Type.Enable = 'on';
            app.FF_Order_S1.Enable = 'on';
            app.FF_Order_S2.Enable = 'off';
            app.FF_BW_S1.Enable = 'on';
            app.FF_BW_S2.Enable = 'off';
            app.FF_Lim_S1.Enable = 'on';
            app.FF_Lim_S2.Enable = 'off';
            app.Est_FF_S1.Enable = 'on';
            app.Est_FF_S2.Enable = 'off';
            app.Est_IF_S1.Enable = 'on';
            app.Est_IF_S2.Enable = 'off';
            
            app.Pole_S2.Visible = 'on';
            app.Pole_S3.Visible = 'off';
            app.Zero_S2.Visible = 'on';
            app.Zero_S3.Visible = 'off';
            app.Pred_Order_S2.Visible = 'on';
            app.Pred_Order_S3.Visible = 'off';
            app.Ang_Ratio_S2.Visible = 'on';
            app.Ang_Ratio_S3.Visible = 'off';
            app.Angle_S2.Visible = 'on';
            app.Angle_S3.Visible = 'off';
            
            app.Term2_S2.Visible = 'on';
            app.Term2_S3.Visible = 'off';
            app.Term3_S2.Visible = 'on';
            app.Term3_S3.Visible = 'off';
            
            elseif value == '3'
                
            app.CLPTS_S2.Visible ='on';
            app.CLPTS_S3.Visible ='on';
            app.Compensate_S1.Visible ='on';
            app.Compensate_S2.Visible ='on';
            app.FF_S1.Visible ='on';
            app.FF_S2.Visible ='on';
            app.IF_S1.Visible ='on';
            app.IF_S2.Visible ='on';
            
            app.Gain_S2.Visible = 'on';
            app.Gain_S3.Visible = 'on';
            app.NL_S2.Visible = 'on';
            app.NL_S3.Visible = 'on';
            app.AmpFFT_S2.Visible ='on';
            app.AmpFFT_S3.Visible ='on';
            app.AmpPlot_S2.Visible ='on';
            app.AmpPlot_S3.Visible ='on';
            
            app.AnaSAT_S2.Visible ='on';
            app.AnaSAT_S3.Visible ='on';
            app.DigSAT_S2.Visible ='on';
            app.DigSAT_S3.Visible ='on';
            app.SATPlot_S2.Visible ='on';
            app.SATPlot_S3.Visible ='on';
            
            app.ADCRes_S2.Visible = 'on';
            app.ADCRes_S3.Visible = 'on';
            app.ADCINL_S2.Visible = 'on';
            app.ADCINL_S3.Visible = 'on';
            app.ADCG_Err_S2.Visible = 'on';
            app.ADCG_Err_S3.Visible = 'on';
            app.ADC_OfsErr_S2.Visible = 'on';
            app.ADC_OfsErr_S3.Visible = 'on';
            app.ADCPlot_S2.Visible = 'on';
            app.ADCPlot_S3.Visible = 'on';
            
            app.DACRes_S2.Visible = 'on';
            app.DACRes_S3.Visible = 'on';
            app.DACINL_S2.Visible = 'on';
            app.DACINL_S3.Visible = 'on';
            app.M0_S2.Visible = 'on';
            app.M0_S3.Visible = 'on';
            app.DoC_S2.Visible = 'on';
            app.DoC_S3.Visible = 'on';
            app.DACPlot_S2.Visible = 'on';
            app.DACPlot_S3.Visible = 'on';
            
            app.FF_Type.Enable = 'on';
            app.FF_Order_S1.Enable = 'on';
            app.FF_Order_S2.Enable = 'on';
            app.FF_BW_S1.Enable = 'on';
            app.FF_BW_S2.Enable = 'on';
            app.FF_Lim_S1.Enable = 'on';
            app.FF_Lim_S2.Enable = 'on';
            app.Est_FF_S1.Enable = 'on';
            app.Est_FF_S2.Enable = 'on';
            app.Est_IF_S1.Enable = 'on';
            app.Est_IF_S2.Enable = 'on';
            
            app.Pole_S2.Visible = 'on';
            app.Pole_S3.Visible = 'on';
            app.Zero_S2.Visible = 'on';
            app.Zero_S3.Visible = 'on';
            app.Pred_Order_S2.Visible = 'on';
            app.Pred_Order_S3.Visible = 'on';
            app.Ang_Ratio_S2.Visible = 'on';
            app.Ang_Ratio_S3.Visible = 'on';
            app.Angle_S2.Visible = 'on';
            app.Angle_S3.Visible = 'on';
            
            app.Term2_S2.Visible = 'on';
            app.Term2_S3.Visible = 'on';
            app.Term3_S2.Visible = 'on';
            app.Term3_S3.Visible = 'on';
            
            end
        end

        % Value changed function: Pipe_Method
        function Pipe_MethodValueChanged(app, event)
            value = app.Pipe_Method.Value;
            
            if strcmp(value,'Custome')
            
            app.CLPTS_S1.Enable ='on';
            app.CLPTS_S2.Enable ='on';
            app.CLPTS_S3.Enable ='on';
            app.Compensate_S1.Enable ='on';
            app.Compensate_S2.Enable ='on';
            app.FF_S1.Enable ='on';
            app.FF_S2.Enable ='on';
            app.IF_S1.Enable ='on';
            app.IF_S2.Enable ='on';
            
            app.FF_Type.Enable = 'on';
            app.FF_Order_S1.Enable = 'on';
            app.FF_Order_S2.Enable = 'on';
            app.FF_BW_S1.Enable = 'on';
            app.FF_BW_S2.Enable = 'on';
            app.FF_Lim_S1.Enable = 'on';
            app.FF_Lim_S2.Enable = 'on';
            app.Est_FF_S1.Enable = 'on';
            app.Est_FF_S2.Enable = 'on';
            app.Est_IF_S1.Enable = 'on';
            app.Est_IF_S2.Enable = 'on';
            
            app.CLPTS_S1.Value = 0;
            app.CLPTS_S2.Value = 0;
            app.CLPTS_S3.Value = 0;
            app.Compensate_S1.Value = 0;
            app.Compensate_S2.Value = 0;
            app.FF_S1.Value = 0;
            app.FF_S2.Value = 0;
            app.IF_S1.Value = 0;
            app.IF_S2.Value = 0;
            
            %app.FF_Type.Value = 'on';
            %app.FF_Order_S1.Value = 'on';
            %app.FF_Order_S2.Value = 'on';
            %app.FF_BW_S1.Value = 'on';
            %app.FF_BW_S2.Value = 'on';
            %app.FF_Lim_S1.Value = 'on';
            %app.FF_Lim_S2.Value = 'on';
            app.Est_FF_S1.Value = 0;
            app.Est_FF_S2.Value = 0;
            app.Est_IF_S1.Value = 0;
            app.Est_IF_S2.Value = 0;
                
            elseif strcmp(value,'Conventional')
             
            app.CLPTS_S1.Enable ='off';  
            app.CLPTS_S2.Enable ='off';
            app.CLPTS_S3.Enable ='off';
            app.Compensate_S1.Enable ='off';
            app.Compensate_S2.Enable ='off';
            app.FF_S1.Enable ='off';
            app.FF_S2.Enable ='off';
            app.IF_S1.Enable ='off';
            app.IF_S2.Enable ='off';
            
            app.FF_Type.Enable = 'off';
            app.FF_Order_S1.Enable = 'off';
            app.FF_Order_S2.Enable = 'off';
            app.FF_BW_S1.Enable = 'off';
            app.FF_BW_S2.Enable = 'off';
            app.FF_Lim_S1.Enable = 'off';
            app.FF_Lim_S2.Enable = 'off';
            app.Est_FF_S1.Enable = 'off';
            app.Est_FF_S2.Enable = 'off';
            app.Est_IF_S1.Enable = 'off';
            app.Est_IF_S2.Enable = 'off';
            
            app.CLPTS_S1.Value = 0;
            app.CLPTS_S2.Value = 0;
            app.CLPTS_S3.Value = 0;
            app.Compensate_S1.Value = 0;
            app.Compensate_S2.Value = 0;
            app.FF_S1.Value = 0;
            app.FF_S2.Value = 0;
            app.IF_S1.Value = 0;
            app.IF_S2.Value = 0;
            
            %app.FF_Type.Value = 'on';
            %app.FF_Order_S1.Value = 'on';
            %app.FF_Order_S2.Value = 'on';
            %app.FF_BW_S1.Value = 'on';
            %app.FF_BW_S2.Value = 'on';
            %app.FF_Lim_S1.Value = 'on';
            %app.FF_Lim_S2.Value = 'on';
            app.Est_FF_S1.Value = 0;
            app.Est_FF_S2.Value = 0;
            app.Est_IF_S1.Value = 0;
            app.Est_IF_S2.Value = 0;
                
            elseif strcmp(value,'FF & IF')
                
            app.CLPTS_S1.Enable ='off';  
            app.CLPTS_S2.Enable ='off';
            app.CLPTS_S3.Enable ='off';
            app.Compensate_S1.Enable ='off';
            app.Compensate_S2.Enable ='off';
            app.FF_S1.Enable ='off';
            app.FF_S2.Enable ='off';
            app.IF_S1.Enable ='off';
            app.IF_S2.Enable ='off';
            
            app.FF_Type.Enable = 'on';
            app.FF_Order_S1.Enable = 'on';
            app.FF_Order_S2.Enable = 'on';
            app.FF_BW_S1.Enable = 'on';
            app.FF_BW_S2.Enable = 'on';
            app.FF_Lim_S1.Enable = 'off';
            app.FF_Lim_S2.Enable = 'off';
            
            app.Est_FF_S1.Enable = 'off';
            app.Est_FF_S2.Enable = 'off';
            app.Est_IF_S1.Enable = 'off';
            app.Est_IF_S2.Enable = 'off';
            
            app.CLPTS_S1.Value = 1;
            app.CLPTS_S2.Value = 1;
            app.CLPTS_S3.Value = 1;
            app.Compensate_S1.Value = 0;
            app.Compensate_S2.Value = 0;
            app.FF_S1.Value = 1;
            app.FF_S2.Value = 1;
            app.IF_S1.Value = 1;
            app.IF_S2.Value = 1;
            
            %app.FF_Type.Value = 'on';
            %app.FF_Order_S1.Value = 'on';
            %app.FF_Order_S2.Value = 'on';
            %app.FF_BW_S1.Value = 'on';
            %app.FF_BW_S2.Value = 'on';
            %app.FF_Lim_S1.Value = 'on';
            %app.FF_Lim_S2.Value = 'on';
            app.Est_FF_S1.Value = 0;
            app.Est_FF_S2.Value = 0;
            app.Est_IF_S1.Value = 0;
            app.Est_IF_S2.Value = 0;
                
            elseif strcmp(value,'FF & Est_IF')
                
            app.CLPTS_S1.Enable ='off';  
            app.CLPTS_S2.Enable ='off';
            app.CLPTS_S3.Enable ='off';
            app.Compensate_S1.Enable ='off';
            app.Compensate_S2.Enable ='off';
            app.FF_S1.Enable ='off';
            app.FF_S2.Enable ='off';
            app.IF_S1.Enable ='off';
            app.IF_S2.Enable ='off';
            
            app.FF_Type.Enable = 'on';
            app.FF_Order_S1.Enable = 'on';
            app.FF_Order_S2.Enable = 'on';
            app.FF_BW_S1.Enable = 'on';
            app.FF_BW_S2.Enable = 'on';
            app.FF_Lim_S1.Enable = 'on';
            app.FF_Lim_S2.Enable = 'on';
            
            app.Est_FF_S1.Enable = 'off';
            app.Est_FF_S2.Enable = 'off';
            app.Est_IF_S1.Enable = 'off';
            app.Est_IF_S2.Enable = 'off';
            
            app.CLPTS_S1.Value = 1;
            app.CLPTS_S2.Value = 1;
            app.CLPTS_S3.Value = 1;
            app.Compensate_S1.Value = 0;
            app.Compensate_S2.Value = 0;
            app.FF_S1.Value = 1;
            app.FF_S2.Value = 1;
            app.IF_S1.Value = 1;
            app.IF_S2.Value = 1;
            
            %app.FF_Type.Value = 'on';
            %app.FF_Order_S1.Value = 'on';
            %app.FF_Order_S2.Value = 'on';
            %app.FF_BW_S1.Value = 'on';
            %app.FF_BW_S2.Value = 'on';
            %app.FF_Lim_S1.Value = 'on';
            %app.FF_Lim_S2.Value = 'on';
            app.Est_FF_S1.Value = 0;
            app.Est_FF_S2.Value = 0;
            app.Est_IF_S1.Value = 1;
            app.Est_IF_S2.Value = 1;
            
            elseif strcmp(value,'Est_FF & IF')
                
            app.CLPTS_S1.Enable ='off';  
            app.CLPTS_S2.Enable ='off';
            app.CLPTS_S3.Enable ='off';
            app.Compensate_S1.Enable ='off';
            app.Compensate_S2.Enable ='off';
            app.FF_S1.Enable ='off';
            app.FF_S2.Enable ='off';
            app.IF_S1.Enable ='off';
            app.IF_S2.Enable ='off';
            
            app.FF_Type.Enable = 'on';
            app.FF_Order_S1.Enable = 'on';
            app.FF_Order_S2.Enable = 'on';
            app.FF_BW_S1.Enable = 'on';
            app.FF_BW_S2.Enable = 'on';
            app.FF_Lim_S1.Enable = 'on';
            app.FF_Lim_S2.Enable = 'on';
            
            app.Est_FF_S1.Enable = 'off';
            app.Est_FF_S2.Enable = 'off';
            app.Est_IF_S1.Enable = 'off';
            app.Est_IF_S2.Enable = 'off';
            
            app.CLPTS_S1.Value = 1;
            app.CLPTS_S2.Value = 1;
            app.CLPTS_S3.Value = 1;
            app.Compensate_S1.Value = 0;
            app.Compensate_S2.Value = 0;
            app.FF_S1.Value = 1;
            app.FF_S2.Value = 1;
            app.IF_S1.Value = 1;
            app.IF_S2.Value = 1;
            
            %app.FF_Type.Value = 'on';
            %app.FF_Order_S1.Value = 'on';
            %app.FF_Order_S2.Value = 'on';
            %app.FF_BW_S1.Value = 'on';
            %app.FF_BW_S2.Value = 'on';
            %app.FF_Lim_S1.Value = 'on';
            %app.FF_Lim_S2.Value = 'on';
            app.Est_FF_S1.Value = 1;
            app.Est_FF_S2.Value = 1;
            app.Est_IF_S1.Value = 0;
            app.Est_IF_S2.Value = 0;
            
            elseif strcmp(value,'Err Compensation')
                
            app.CLPTS_S1.Enable ='off';  
            app.CLPTS_S2.Enable ='off';
            app.CLPTS_S3.Enable ='off';
            app.Compensate_S1.Enable ='off';
            app.Compensate_S2.Enable ='off';
            app.FF_S1.Enable ='off';
            app.FF_S2.Enable ='off';
            app.IF_S1.Enable ='off';
            app.IF_S2.Enable ='off';
            
            app.FF_Type.Enable = 'on';
            app.FF_Order_S1.Enable = 'on';
            app.FF_Order_S2.Enable = 'on';
            app.FF_BW_S1.Enable = 'on';
            app.FF_BW_S2.Enable = 'on';
            app.FF_Lim_S1.Enable = 'off';
            app.FF_Lim_S2.Enable = 'off';
            
            app.Est_FF_S1.Enable = 'off';
            app.Est_FF_S2.Enable = 'off';
            app.Est_IF_S1.Enable = 'off';
            app.Est_IF_S2.Enable = 'off';
            
            app.CLPTS_S1.Value = 1;
            app.CLPTS_S2.Value = 1;
            app.CLPTS_S3.Value = 1;
            app.Compensate_S1.Value = 1;
            app.Compensate_S2.Value = 1;
            app.FF_S1.Value = 1;
            app.FF_S2.Value = 1;
            app.IF_S1.Value = 0;
            app.IF_S2.Value = 0;
            
            %app.FF_Type.Value = 'on';
            %app.FF_Order_S1.Value = 'on';
            %app.FF_Order_S2.Value = 'on';
            %app.FF_BW_S1.Value = 'on';
            %app.FF_BW_S2.Value = 'on';
            %app.FF_Lim_S1.Value = 'on';
            %app.FF_Lim_S2.Value = 'on';
            app.Est_FF_S1.Value = 0;
            app.Est_FF_S2.Value = 0;
            app.Est_IF_S1.Value = 0;
            app.Est_IF_S2.Value = 0;
                
            end
            
        end

        % Button pushed function: RunSimulation_2
        function RunSimulation_2ButtonPushed(app, event)
            app.status_2.Value = 'Filter is being created';
            
            pause(1)
            
            alpha_2 = [app.Zero_S1.Value; app.Zero_S2.Value; app.Zero_S3.Value];
            beta_2 = [app.Pole_S1.Value; app.Pole_S2.Value; app.Pole_S3.Value];
            Order_2 = [app.Pred_Order_S1.Value; app.Pred_Order_S2.Value; app.Pred_Order_S3.Value];
            Pred_Type_2 = app.PredType_2.Value;
            angle_ratio_2 = [app.Ang_Ratio_S1.Value; app.Ang_Ratio_S2.Value; app.Ang_Ratio_S3.Value];
            OSR2 = app.OSR_2.Value;
            OSR2 = 2^OSR2;
            
            Predictor_feature_2 = {alpha_2,beta_2,Order_2,angle_ratio_2,OSR2};
            k_2 = [app.Gain_S1.Value; app.Gain_S2.Value; app.Gain_S3.Value];
            
            
            % Decimator
            LPF_order_2 = app.OrderLPF_2.Value;
            LPF_Type_2 = app.LPFType_2.Value;
            
            N = app.Num_Stage.Value;
            

            if strcmp(Pred_Type_2,'Real pole-Real zero')
                P_Type_2 = 1;
            elseif strcmp(Pred_Type_2,'Real pole-Complex zero')
                P_Type_2 = 2;
            elseif strcmp(Pred_Type_2,'Complex pole-Complex zero')
                P_Type_2 = 3;    
            end
            
            global Resp_Loop2
            global freq_Loop2
            global Resp_H2
            global freq_H2
            global h_LPF2
            global w_LPF2
            
                       
            [angle_2,H_N_2,H_D_2,LPF_N_2,LPF_D_2,Resp_Loop2,freq_Loop2,Resp_H2,freq_H2,h_LPF2,w_LPF2,Term] = ...
            PredictorFilter_Pipe(k_2,Predictor_feature_2,P_Type_2,LPF_order_2,LPF_Type_2,N);
           %[angle,H_N,H_D,LPF_N,LPF_D,Resp_Loop,freq_Loop,Resp_H,freq_H,h_LPF,w_LPF,Term]
            app.Angle_S1.Value = angle_2(1);
            app.Angle_S2.Value = angle_2(2);
            app.Angle_S3.Value = angle_2(3);


            %plot(app.H,freq_H,Resp_H)
            %plot(app.Loop,freq_Loop,Resp_Loop)
            %plot(app.PZ,w_LPF,h_LPF)
%           

            app.Term2_S1.Value = Term{1}(1);
            app.Term2_S2.Value = Term{1}(2);
            app.Term2_S3.Value = Term{1}(3);
            app.Term3_S1.Value = Term{2}(1);
            app.Term3_S2.Value = Term{2}(2);
            app.Term3_S3.Value = Term{2}(3);
            app.M0_S1.Value = abs(Term{3}(1));
            app.M0_S2.Value = abs(Term{3}(2));
            app.M0_S3.Value = abs(Term{3}(3));
            app.DoC_S1.Value = abs(Term{4}(1));
            app.DoC_S2.Value = abs(Term{4}(2));
            app.DoC_S3.Value = abs(Term{4}(3));
            
            global freq_FF
            global freq_IF
            global Resp_FF
            global Resp_IF
            
            FF_Order = [app.FF_Order_S1.Value, app.FF_Order_S2.Value];
            FF_Limit = [app.FF_Lim_S1.Value, app.FF_Lim_S2.Value];
            Est_FF = [app.FF_S1.Value, app.FF_S2.Value; app.Est_FF_S1.Value, app.Est_FF_S2.Value];
            Est_IF = [app.IF_S1.Value, app.IF_S2.Value; app.Est_IF_S1.Value, app.Est_IF_S2.Value];
            FF_Type1 = app.FF_Type.Value;
            
            
            [FF_N,FF_D,IF_N,IF_D,freq_FF,Resp_FF,freq_IF,Resp_IF] = ...
            FF_IF_Designer(FF_Order,FF_Limit,Est_FF,Est_IF,FF_Type1,N,OSR2);
            
%             app.BW_2.Value = BW0;
%             app.FF_BW_S1 = BW0;
%             app.FF_BW_S2 = BW0;
            
            app.status_2.Value = 'Filter realization completed !';
            
            pause(1)
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.status_2.Value = 'Simulation is running';
            
             pause(1)
             
            Plot_status = {};
            Plot_status{1,1} = app.Pred_Plot_S1.Value;
            Plot_status{1,2} = app.Pred_Plot_S2.Value;
            Plot_status{1,3} = app.Pred_Plot_S3.Value;

            
            % Input Signal
            Sweep_2 = app.AmpSweep_2.Value;
            Amp_2 = app.Amplitude_2.Value;
            In_Type_2 = app.InputType_2.Value;
            Fin_2 = app.InputFreq_2.Value;
            Plot_status{2,1} = app.InputPlot_2.Value;
            Input_feature_2 = {Sweep_2,Amp_2,Fin_2};
            
            % Sample and Hold
            Plot_status{3,1} = app.SHPlot_2.Value;
            
            NFFT2 = app.NFFT_2.Value;
            NFFT2 = 2^NFFT2;
            R2 = app.R_2.Value;
            R2 = 2^R2;
            SH_feature_2 = [OSR2,NFFT2,R2];
            
            % Amplifier
            Plot_status{4,1} = app.AmpPlot_S1.Value;
            Plot_status{4,2} = app.AmpPlot_S2.Value;
            Plot_status{4,3} = app.AmpPlot_S3.Value;
            Plot_status{13,1} = app.AmpFFT_S1.Value;
            Plot_status{13,2} = app.AmpFFT_S2.Value;
            Plot_status{13,3} = app.AmpFFT_S3.Value;
            Amp_NL_2 = [app.NL_S1.Value; app.NL_S2.Value; app.NL_S3.Value];
            Amplifier_feature_2 = {k_2,Amp_NL_2};
            
            % Saturation Blocks
            Plot_status{5,1} = app.SATPlot_S1.Value;
            Plot_status{5,2} = app.SATPlot_S2.Value;
            Plot_status{5,3} = app.SATPlot_S3.Value;
            %Plot_status{6} = app.PlotDigital.Value;
            LimAna_2 = [app.AnaSAT_S1.Value; app.AnaSAT_S2.Value; app.AnaSAT_S3.Value];
            SAT_2 = LimAna_2;
            
            % ADC
            Plot_status{7,1} = app.ADCPlot_S1.Value;
            Plot_status{7,2} = app.ADCPlot_S2.Value;
            Plot_status{7,3} = app.ADCPlot_S3.Value;
            INL_ADC_2 = [app.ADCINL_S1.Value; app.ADCINL_S2.Value; app.ADCINL_S3.Value];
            ADC_res_2 = [app.ADCRes_S1.Value; app.ADCRes_S2.Value; app.ADCRes_S3.Value];
            ADC_feature_2 = {ADC_res_2,INL_ADC_2};
            
            % DAC
            Plot_status{8,1} = app.DACPlot_S1.Value;
            Plot_status{8,2} = app.DACPlot_S2.Value;
            Plot_status{8,3} = app.DACPlot_S3.Value;
            INL_DAC_2 = [app.DACINL_S1.Value; app.DACINL_S2.Value; app.DACINL_S3.Value];
            DAC_res_2 = [app.DACRes_S1.Value; app.DACRes_S2.Value; app.DACRes_S3.Value];
            DAC_feature_2 = {DAC_res_2,INL_DAC_2};
            
            
            % Out
            Plot_status{9,1} = app.PlotTimeout_2.Value;
            Plot_status{10,1} = app.PlotFFTout_2.Value;
            
            % Out Filtered
            Plot_status{11,1} = app.PlotTimeoutf_2.Value;
            Plot_status{12,2} = app.PlotFFToutf_2.Value; 
            
            Plot_status{14,1} = app.comparison_2.Value;
            
            CLPTS = [app.CLPTS_S1.Value,app.CLPTS_S2.Value,app.CLPTS_S3.Value];
            Compens = [app.Compensate_S1.Value,app.Compensate_S2.Value];
            FF = [app.FF_S1.Value,app.FF_S2.Value];
            IF = [app.IF_S1.Value,app.IF_S2.Value];
            
            Method_2 = {CLPTS,Compens,FF,IF};
            
            if app.AmpSweep_2.Value
                
        
            [Amp_m_2,fs_2,BW0_2,SNDR_2,ENOB_2,SNDR_f_2,ENOB_f_2,SNDR_t_2,ENOB_t_2,SAT_Dig_2] = CLPTS_Pipe_ADC_Sweep(Plot_status,...
            DAC_feature_2, ADC_feature_2, SAT_2, Amplifier_feature_2,...
            SH_feature_2, Input_feature_2,In_Type_2,H_N_2,H_D_2,LPF_N_2,LPF_D_2,Method_2,N);
            

            app.Amplitude_2.Value = Amp_m_2;
            
            else
                
            [fs_2,BW0_2,SNDR_2,ENOB_2,SNDR_f_2,ENOB_f_2,SNDR_t_2,ENOB_t_2,SAT_Dig_2] = CLPTS_Pipe_ADC(Plot_status,...
            DAC_feature_2, ADC_feature_2, SAT_2, Amplifier_feature_2,...
            SH_feature_2, Input_feature_2,In_Type_2,H_N_2,H_D_2,LPF_N_2,LPF_D_2,Method_2,N,FF_N,FF_D,IF_N,IF_D);
            
            end
            
            app.Fs_2.Value = fs_2;
            app.BW_2.Value = BW0_2;
            app.FF_BW_S1.Value = BW0_2;
            app.FF_BW_S2.Value = BW0_2;
            app.SNDRout_2.Value = SNDR_2;
            app.ENOBout_2.Value = ENOB_2;
            app.SNDRoutf_2.Value = SNDR_f_2;
            app.ENOBoutf_2.Value = ENOB_f_2;
            app.ENOB_t_out_2.Value = ENOB_t_2;
            app.DigSAT_S1.Value = SAT_Dig_2(1);
            app.DigSAT_S2.Value = SAT_Dig_2(2);
            app.DigSAT_S3.Value = SAT_Dig_2(3);
            
            app.status_2.Value = 'Simulation completed !';

        end

        % Button pushed function: CreateFilterButton_2
        function CreateFilterButton_2Pushed(app, event)
            
            app.status_2.Value = 'Filter is being created';
            
            pause(1)
            
            alpha_2 = [app.Zero_S1.Value; app.Zero_S2.Value; app.Zero_S3.Value];
            beta_2 = [app.Pole_S1.Value; app.Pole_S2.Value; app.Pole_S3.Value];
            Order_2 = [app.Pred_Order_S1.Value; app.Pred_Order_S2.Value; app.Pred_Order_S3.Value];
            Pred_Type_2 = app.PredType_2.Value;
            angle_ratio_2 = [app.Ang_Ratio_S1.Value; app.Ang_Ratio_S2.Value; app.Ang_Ratio_S3.Value];
            OSR2 = app.OSR_2.Value;
            OSR2 = 2^OSR2;
            
            Predictor_feature_2 = {alpha_2,beta_2,Order_2,angle_ratio_2,OSR2};
            k_2 = [app.Gain_S1.Value; app.Gain_S2.Value; app.Gain_S3.Value];
            
            
            % Decimator
            LPF_order_2 = app.OrderLPF_2.Value;
            LPF_Type_2 = app.LPFType_2.Value;
            
            N = app.Num_Stage.Value;
            

            if strcmp(Pred_Type_2,'Real pole-Real zero')
                P_Type_2 = 1;
            elseif strcmp(Pred_Type_2,'Real pole-Complex zero')
                P_Type_2 = 2;
            elseif strcmp(Pred_Type_2,'Complex pole-Complex zero')
                P_Type_2 = 3;    
            end


            [angle_2,H_N_2,H_D_2,LPF_N_2,LPF_D_2,Resp_Loop2,freq_Loop2,Resp_H2,freq_H2,h_LPF2,w_LPF2,Term] = ...
            PredictorFilter_Pipe(k_2,Predictor_feature_2,P_Type_2,LPF_order_2,LPF_Type_2,N);
           %[angle,H_N,H_D,LPF_N,LPF_D,Resp_Loop,freq_Loop,Resp_H,freq_H,h_LPF,w_LPF,Term]
            app.Angle_S1.Value = angle_2(1);
            app.Angle_S2.Value = angle_2(2);
            app.Angle_S3.Value = angle_2(3);


            %plot(app.H,freq_H,Resp_H)
            %plot(app.Loop,freq_Loop,Resp_Loop)
            %plot(app.PZ,w_LPF,h_LPF)
%           

            app.Term2_S1.Value = Term{1}(1);
            app.Term2_S2.Value = Term{1}(2);
            app.Term2_S3.Value = Term{1}(3);
            app.Term3_S1.Value = Term{2}(1);
            app.Term3_S2.Value = Term{2}(2);
            app.Term3_S3.Value = Term{2}(3);
            app.M0_S1.Value = abs(Term{3}(1));
            app.M0_S2.Value = abs(Term{3}(2));
            app.M0_S3.Value = abs(Term{3}(3));
            app.DoC_S1.Value = abs(Term{4}(1));
            app.DoC_S2.Value = abs(Term{4}(2));
            app.DoC_S3.Value = abs(Term{4}(3));
            
            
            FF_Order = [app.FF_Order_S1.Value, app.FF_Order_S2.Value];
            FF_Limit = [app.FF_Lim_S1.Value, app.FF_Lim_S2.Value];
            Est_FF = [app.FF_S1.Value, app.FF_S2.Value; app.Est_FF_S1.Value, app.Est_FF_S2.Value];
            Est_IF = [app.IF_S1.Value, app.IF_S2.Value; app.Est_IF_S1.Value, app.Est_IF_S2.Value];
            FF_Type1 = app.FF_Type.Value;
            
            
            [FF_N,FF_D,IF_N,IF_D,freq_FF,Resp_FF,freq_IF,Resp_IF] = ...
            FF_IF_Designer(FF_Order,FF_Limit,Est_FF,Est_IF,FF_Type1,N,OSR2);
            
%             app.BW_2.Value = BW0;
%             app.FF_BW_S1 = BW0;
%             app.FF_BW_S2 = BW0;
            save('XXX','freq_FF','Resp_FF','freq_IF','Resp_IF',...
                'Resp_Loop2','freq_Loop2','Resp_H2','freq_H2','h_LPF2','w_LPF2');
            app.status_2.Value = 'Filter realization completed !';
            
            pause(1)
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
           
        end

        % Value changed function: Fig_Plot_type
        function Fig_Plot_typeValueChanged(app, event)
            value_t = app.Fig_Plot_type.Value;
            value_s = app.Fig_Plot_Stage.Value;
            value_s = str2num(value_s);
            load('XXX.mat')
            
            if strcmp(value_t,'Decimator')
                try
                app.Pred_2.Title.String = 'Decimator Freq Resp';
                semilogy(app.Pred_2,w_LPF2,abs(h_LPF2))
                
                xlim auto
                ylim auto
                end
                
            elseif strcmp(value_t,'Loop')
                try
                app.Pred_2.Title.String = 'Loop Freq Resp';
                plot(app.Pred_2,freq_Loop2(:,value_s),abs(Resp_Loop2(:,value_s)))
                xlim auto
                ylim auto
                end
            elseif strcmp(value_t,'Predictor')
                try
                app.Pred_2.Title.String = 'Predictor Freq Resp';
                semilogy(app.Pred_2,freq_H2(:,value_s),abs(Resp_H2(:,value_s)))
                xlim auto
                ylim auto
                end
            end          

        end

        % Value changed function: Fig_Plot_Stage
        function Fig_Plot_StageValueChanged(app, event)
            
            value_t = app.Fig_Plot_type.Value;
            value_s = app.Fig_Plot_Stage.Value;
            value_s = str2num(value_s);
            load('XXX.mat')
            
            if strcmp(value_t,'Decimator')
                try
                app.Pred_2.Title.String = 'Decimator Freq Resp';
                semilogy(app.Pred_2,w_LPF2,abs(h_LPF2))
                xlim auto
                ylim auto
                end
            elseif strcmp(value_t,'Loop')
                try
                app.Pred_2.Title.String = 'Loop Freq Resp';
                plot(app.Pred_2,freq_Loop2(:,value_s),abs(Resp_Loop2(:,value_s)))
                xlim auto
                ylim auto
                end
            elseif strcmp(value_t,'Predictor')
                try
                app.Pred_2.Title.String = 'Predictor Freq Resp';
                semilogy(app.Pred_2,freq_H2(:,value_s),abs(Resp_H2(:,value_s)))
                xlim auto
                ylim auto
                end
            end  
        end

        % Value changed function: Filter_Plot_type
        function Filter_Plot_typeValueChanged(app, event)
            
            value_t = app.Filter_Plot_type.Value;
            value_s = app.Filter_Plot_Stage.Value;
            value_s = str2num(value_s);
            load('XXX.mat')
            
            if strcmp(value_t,'FF')
                try
                app.FF_IF_2.Title.String = 'FF Freq Resp'; 
                semilogy(app.FF_IF_2,freq_FF{value_s},abs(Resp_FF{value_s}))
                xlim auto
                ylim auto
                end
            elseif strcmp(value_t,'IF')
                try
                app.FF_IF_2.Title.String = 'IF Freq Resp';
                plot(app.FF_IF_2,freq_IF{value_s},abs(Resp_IF{value_s}))
                xlim auto
                ylim auto
                end
            end  
        end

        % Value changed function: Filter_Plot_Stage
        function Filter_Plot_StageValueChanged(app, event)
            
            value_t = app.Filter_Plot_type.Value
            value_s = app.Filter_Plot_Stage.Value
            value_s = str2num(value_s);
            load('XXX.mat')
            
            if strcmp(value_t,'FF')
                try
                app.FF_IF_2.Title.String = 'FF Freq Resp'; 
                semilogy(app.FF_IF_2,freq_FF{value_s},abs(Resp_FF{value_s}))
                xlim auto
                ylim auto 
                end
            elseif strcmp(value_t,'IF')
                try
                app.FF_IF_2.Title.String = 'IF Freq Resp';
                plot(app.FF_IF_2,freq_IF{value_s},abs(Resp_IF{value_s}))
                xlim auto
                ylim auto
                end
            end 
            
        end

        % Value changed function: AmpSweep_2
        function AmpSweep_2ValueChanged(app, event)
            value = app.AmpSweep_2.Value;
            if value
            app.Amplitude_2.BackgroundColor = [0.65 0.65 0.65];
            app.Amplitude_2.Value = 0;
            else
            app.Amplitude_2.BackgroundColor = [1 1 1];
            app.Amplitude_2.Value = 0; 
            end
            pause(1)
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CLPTSADC = uifigure('Visible', 'off');
            app.CLPTSADC.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.CLPTSADC);
            app.SingleADCTab = uitab(app.TabGroup);
            app.SingleADCTab.Title = 'Single ADC';
            app.H = uiaxes(app.SingleADCTab);
            title(app.H, 'Predictor Freq Response')
            xlabel(app.H, 'Freq')
            ylabel(app.H, 'Gain')
            app.Loop = uiaxes(app.SingleADCTab);
            xlabel(app.Loop, 'Freq')
            ylabel(app.Loop, 'Gain')
            app.PZ = uiaxes(app.SingleADCTab);
            title(app.PZ, 'LPF Freq Response')
            xlabel(app.PZ, 'Freq')
            ylabel(app.PZ, 'Gain')
            app.Input = uipanel(app.SingleADCTab);
            app.Input.Title = 'Input Signal';
            app.AmpSweep = uicheckbox(app.Input);
            app.AmpSweep.Text = 'Sweep';
            app.AmplitudeLabel = uilabel(app.Input);
            app.AmplitudeLabel.Text = 'Amplitude:';
            app.Amplitude = uieditfield(app.Input, 'numeric');
            app.Amplitude.Value = 0.8;
            app.TypeDropDownLabel = uilabel(app.Input);
            app.TypeDropDownLabel.Text = 'Type:';
            app.InputType = uidropdown(app.Input);
            app.InputType.Items = {'Sine', 'Chirp', 'Multitone', ''};
            app.InputType.Value = 'Sine';
            app.FreqLabel_2 = uilabel(app.Input);
            app.FreqLabel_2.Text = 'Freq:         ';
            app.InputFreq = uieditfield(app.Input, 'numeric');
            app.InputFreq.Value = 1;
            app.InputPlot = uicheckbox(app.Input);
            app.InputPlot.Text = 'Plot';
            app.SampleHold = uipanel(app.SingleADCTab);
            app.SampleHold.Title = 'Sample & Hold';
            app.OSRLabel = uilabel(app.SampleHold);
            app.OSRLabel.Text = 'OSR:               2^';
            app.SHPlot = uicheckbox(app.SampleHold);
            app.SHPlot.Text = 'Plot';
            app.OSR = uieditfield(app.SampleHold, 'numeric');
            app.OSR.Value = 4;
            app.ofFFTpts2Label = uilabel(app.SampleHold);
            app.ofFFTpts2Label.Text = '# of FFT pts:  2^';
            app.NFFT = uieditfield(app.SampleHold, 'numeric');
            app.NFFT.Value = 7;
            app.Repeatition2Label = uilabel(app.SampleHold);
            app.Repeatition2Label.Text = 'Repeatition:   2^';
            app.R = uieditfield(app.SampleHold, 'numeric');
            app.R.Value = 8;
            app.FsLabel = uilabel(app.SampleHold);
            app.FsLabel.Text = 'Fs:       ';
            app.Fs = uieditfield(app.SampleHold, 'numeric');
            app.Fs.Editable = 'off';
            app.AmplifierPanel = uipanel(app.SingleADCTab);
            app.AmplifierPanel.Title = 'Amplifier';
            app.NonlinearityAMPLabel = uilabel(app.AmplifierPanel);
            app.NonlinearityAMPLabel.Text = 'Nonlinearity:';
            app.AmpPlot = uicheckbox(app.AmplifierPanel);
            app.AmpPlot.Text = 'Plot';
            app.NonlinearityAmp = uieditfield(app.AmplifierPanel, 'numeric');
            app.GainLabel = uilabel(app.AmplifierPanel);
            app.GainLabel.Text = 'Gain:             ';
            app.Gain = uieditfield(app.AmplifierPanel, 'numeric');
            app.Gain.Value = 8;
            app.AmpPlot_FFT = uicheckbox(app.AmplifierPanel);
            app.AmpPlot_FFT.Text = 'FFT';
            app.SaturationPanel = uipanel(app.SingleADCTab);
            app.SaturationPanel.Title = 'Saturation';
            app.LimitDigitalLabel = uilabel(app.SaturationPanel);
            app.LimitDigitalLabel.Text = 'Limit (Digital):';
            app.PlotAnalog = uicheckbox(app.SaturationPanel);
            app.PlotAnalog.Text = 'Plot (Analog side)';
            app.LimitDigital = uieditfield(app.SaturationPanel, 'numeric');
            app.LimitDigital.Editable = 'off';
            app.LimitAnalogLabel = uilabel(app.SaturationPanel);
            app.LimitAnalogLabel.Text = 'Limit (Analog):';
            app.LimitAnalog = uieditfield(app.SaturationPanel, 'numeric');
            app.LimitAnalog.Value = 1;
            app.PlotDigital = uicheckbox(app.SaturationPanel);
            app.PlotDigital.Text = 'Plot (Digital side)';
            app.ADCPanel = uipanel(app.SingleADCTab);
            app.ADCPanel.Title = 'ADC';
            app.Max_INLLSBLabel = uilabel(app.ADCPanel);
            app.Max_INLLSBLabel.Text = 'Max_INL (LSB):       ';
            app.ADCPlot = uicheckbox(app.ADCPanel);
            app.ADCPlot.Text = 'Plot';
            app.ADCINL = uieditfield(app.ADCPanel, 'numeric');
            app.ResolutionBitLabel = uilabel(app.ADCPanel);
            app.ResolutionBitLabel.Text = 'Resolution (Bit):';
            app.ADCres = uieditfield(app.ADCPanel, 'numeric');
            app.ADCres.Value = 10;
            app.PredictorPanel = uipanel(app.SingleADCTab);
            app.PredictorPanel.Title = 'Predictor';
            app.PredPlot = uicheckbox(app.PredictorPanel);
            app.PredPlot.Text = 'Plot';
            app.ZeroLabel = uilabel(app.PredictorPanel);
            app.ZeroLabel.Text = 'Zero:  ';
            app.Alpha = uieditfield(app.PredictorPanel, 'numeric');
            app.Alpha.Value = 0.95;
            app.TypeLabel = uilabel(app.PredictorPanel);
            app.TypeLabel.Text = 'Type:   ';
            app.PredType = uidropdown(app.PredictorPanel);
            app.PredType.Items = {'Real pole-Real zero', 'Real pole-Complex zero', 'Complex pole-Complex zero'};
            app.PredType.Value = 'Real pole-Complex zero';
            app.PoleLabel = uilabel(app.PredictorPanel);
            app.PoleLabel.Text = 'Pole:           ';
            app.Beta = uieditfield(app.PredictorPanel, 'numeric');
            app.AngleratioLabel = uilabel(app.PredictorPanel);
            app.AngleratioLabel.Text = 'Angle ratio:  ';
            app.AngleRatio = uieditfield(app.PredictorPanel, 'numeric');
            app.AngleRatio.Value = 1;
            app.AngleLabel = uilabel(app.PredictorPanel);
            app.AngleLabel.Text = 'Angle:           ';
            app.Angle = uieditfield(app.PredictorPanel, 'numeric');
            app.Angle.Editable = 'off';
            app.OrderLabel_2 = uilabel(app.PredictorPanel);
            app.OrderLabel_2.Text = 'Order:';
            app.PredOrder = uieditfield(app.PredictorPanel, 'numeric');
            app.PredOrder.Value = 2;
            app.DACPanel = uipanel(app.SingleADCTab);
            app.DACPanel.Title = 'DAC';
            app.Max_INLLSBLabel_2 = uilabel(app.DACPanel);
            app.Max_INLLSBLabel_2.Text = 'Max_INL (LSB):       ';
            app.DACPlot = uicheckbox(app.DACPanel);
            app.DACPlot.Text = 'Plot';
            app.DACINL = uieditfield(app.DACPanel, 'numeric');
            app.ResolutionBitLabel_2 = uilabel(app.DACPanel);
            app.ResolutionBitLabel_2.Text = 'Resolution (Bit):';
            app.DACres = uieditfield(app.DACPanel, 'numeric');
            app.DACres.Value = 6;
            app.M0BitLabel = uilabel(app.DACPanel);
            app.M0BitLabel.Text = 'M0 (Bit):             ';
            app.M0 = uieditfield(app.DACPanel, 'numeric');
            app.M0.Editable = 'off';
            app.DoCLabel = uilabel(app.DACPanel);
            app.DoCLabel.Text = 'DoC:                   ';
            app.DoC = uieditfield(app.DACPanel, 'numeric');
            app.DoC.Editable = 'off';
            app.FilterSpecsPanel = uipanel(app.SingleADCTab);
            app.FilterSpecsPanel.Title = 'Filter Specs';
            app.Term3Label = uilabel(app.FilterSpecsPanel);
            app.Term3Label.Text = 'Term 3:    ';
            app.Term3 = uieditfield(app.FilterSpecsPanel, 'numeric');
            app.Term3.Editable = 'off';
            app.Term2Label = uilabel(app.FilterSpecsPanel);
            app.Term2Label.Text = 'Term 2:        ';
            app.Term2 = uieditfield(app.FilterSpecsPanel, 'numeric');
            app.Term2.Editable = 'off';
            app.CreateFilterButton = uibutton(app.SingleADCTab, 'push');
            app.CreateFilterButton.ButtonPushedFcn = createCallbackFcn(app, @CreateFilterButtonPushed, true);
            app.CreateFilterButton.Text = 'Create Filter';
            app.DecimatorPanel = uipanel(app.SingleADCTab);
            app.DecimatorPanel.Title = 'Decimator';
            app.BWLabel = uilabel(app.DecimatorPanel);
            app.BWLabel.Text = 'BW:            ';
            app.BW = uieditfield(app.DecimatorPanel, 'numeric');
            app.BW.Editable = 'off';
            app.TypeDropDown_3Label = uilabel(app.DecimatorPanel);
            app.TypeDropDown_3Label.Text = 'Type:';
            app.LPFType = uidropdown(app.DecimatorPanel);
            app.LPFType.Items = {'FIR', 'Butterworth', 'Chebyshev I', 'Chebyshev II', ''};
            app.LPFType.Value = 'Butterworth';
            app.OrderLabel = uilabel(app.DecimatorPanel);
            app.OrderLabel.Text = 'Order:       ';
            app.OrderLPF = uieditfield(app.DecimatorPanel, 'numeric');
            app.OrderLPF.Value = 2;
            app.ADCfig = uiimage(app.SingleADCTab);
            app.ADCfig.ImageSource = 'GUI_fig.PNG';
            app.OutputPanel = uipanel(app.SingleADCTab);
            app.OutputPanel.Title = 'Output';
            app.ENOBBitLabel = uilabel(app.OutputPanel);
            app.ENOBBitLabel.Text = 'ENOB (Bit):  ';
            app.ENOBout = uieditfield(app.OutputPanel, 'numeric');
            app.ENOBout.Editable = 'off';
            app.PlotTimeDropDownLabel = uilabel(app.OutputPanel);
            app.PlotTimeDropDownLabel.Text = 'Plot (Time): ';
            app.PlotTimeout = uidropdown(app.OutputPanel);
            app.PlotTimeout.Items = {'None', 'Only Out', 'Out and Input', ''};
            app.PlotTimeout.Value = 'None';
            app.SNDRdBLabel = uilabel(app.OutputPanel);
            app.SNDRdBLabel.Text = 'SNDR (dB):  ';
            app.SNDRout = uieditfield(app.OutputPanel, 'numeric');
            app.SNDRout.Editable = 'off';
            app.PlotFFTout = uicheckbox(app.OutputPanel);
            app.PlotFFTout.Text = 'Plot (FFT)';
            app.ENOB_tBitLabel = uilabel(app.OutputPanel);
            app.ENOB_tBitLabel.Text = 'ENOB_t (Bit):';
            app.ENOB_t_out = uieditfield(app.OutputPanel, 'numeric');
            app.ENOB_t_out.Editable = 'off';
            app.OutTypeLabel = uilabel(app.OutputPanel);
            app.OutTypeLabel.Text = 'Out Type: ';
            app.Out_type = uidropdown(app.OutputPanel);
            app.Out_type.Items = {'Delta-Modulator', 'Two-Step'};
            app.Out_type.Value = 'Delta-Modulator';
            app.RunSimulation = uibutton(app.SingleADCTab, 'push');
            app.RunSimulation.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulation.Text = 'Run Simulation';
            app.UBCfig = uiimage(app.SingleADCTab);
            app.UBCfig.ImageSource = 'UBC.jpg';
            app.comparison = uicheckbox(app.SingleADCTab);
            app.comparison.Text = 'Prediction vs Input';
            app.status = uieditfield(app.SingleADCTab, 'text');
            app.status.Editable = 'off';
            app.FilteredOutputPanel = uipanel(app.SingleADCTab);
            app.FilteredOutputPanel.Title = 'Filtered Output';
            app.ENOBBitLabel_2 = uilabel(app.FilteredOutputPanel);
            app.ENOBBitLabel_2.Text = 'ENOB (Bit):  ';
            app.ENOBoutf = uieditfield(app.FilteredOutputPanel, 'numeric');
            app.ENOBoutf.Editable = 'off';
            app.PlotTimeDropDown_2Label = uilabel(app.FilteredOutputPanel);
            app.PlotTimeDropDown_2Label.Text = 'Plot (Time): ';
            app.PlotTimeoutf = uidropdown(app.FilteredOutputPanel);
            app.PlotTimeoutf.Items = {'None', 'Only Out', 'Out and Input', ''};
            app.PlotTimeoutf.Value = 'None';
            app.SNDRdBLabel_2 = uilabel(app.FilteredOutputPanel);
            app.SNDRdBLabel_2.Text = 'SNDR (dB):  ';
            app.SNDRoutf = uieditfield(app.FilteredOutputPanel, 'numeric');
            app.SNDRoutf.Editable = 'off';
            app.PlotFFToutf = uicheckbox(app.FilteredOutputPanel);
            app.PlotFFToutf.Text = 'Plot (FFT)';
            app.PipelineADCTab = uitab(app.TabGroup);
            app.PipelineADCTab.Title = 'Pipeline ADC';
            app.FF_IF_2 = uiaxes(app.PipelineADCTab);
            xlabel(app.FF_IF_2, 'Freq')
            ylabel(app.FF_IF_2, 'Gain')
            app.Pred_2 = uiaxes(app.PipelineADCTab);
            xlabel(app.Pred_2, 'Freq')
            ylabel(app.Pred_2, 'Gain')
            app.Input_2 = uipanel(app.PipelineADCTab);
            app.Input_2.Title = 'Input Signal';
            app.AmpSweep_2 = uicheckbox(app.Input_2);
            app.AmpSweep_2.ValueChangedFcn = createCallbackFcn(app, @AmpSweep_2ValueChanged, true);
            app.AmpSweep_2.Text = 'Sweep';
            app.AmplitudeLabel_2 = uilabel(app.Input_2);
            app.AmplitudeLabel_2.Text = 'Amplitude:';
            app.Amplitude_2 = uieditfield(app.Input_2, 'numeric');
            app.Amplitude_2.Value = 0.9;
            app.TypeDropDownLabel_2 = uilabel(app.Input_2);
            app.TypeDropDownLabel_2.Text = 'Type:';
            app.InputType_2 = uidropdown(app.Input_2);
            app.InputType_2.Items = {'Sine', 'Chirp', 'Multitone', ''};
            app.InputType_2.Value = 'Sine';
            app.FreqLabel_3 = uilabel(app.Input_2);
            app.FreqLabel_3.Text = 'Freq:         ';
            app.InputFreq_2 = uieditfield(app.Input_2, 'numeric');
            app.InputFreq_2.Value = 1;
            app.InputPlot_2 = uicheckbox(app.Input_2);
            app.InputPlot_2.Text = 'Plot';
            app.SampleHold_2 = uipanel(app.PipelineADCTab);
            app.SampleHold_2.Title = 'Sample & Hold';
            app.OSRLabel_2 = uilabel(app.SampleHold_2);
            app.OSRLabel_2.Text = 'OSR:               2^';
            app.SHPlot_2 = uicheckbox(app.SampleHold_2);
            app.SHPlot_2.Text = 'Plot';
            app.OSR_2 = uieditfield(app.SampleHold_2, 'numeric');
            app.OSR_2.Value = 4;
            app.ofFFTpts2Label_2 = uilabel(app.SampleHold_2);
            app.ofFFTpts2Label_2.Text = '# of FFT pts:  2^';
            app.NFFT_2 = uieditfield(app.SampleHold_2, 'numeric');
            app.NFFT_2.Value = 7;
            app.Repeatition2Label_2 = uilabel(app.SampleHold_2);
            app.Repeatition2Label_2.Text = 'Repeatition:   2^';
            app.R_2 = uieditfield(app.SampleHold_2, 'numeric');
            app.R_2.Value = 8;
            app.FsLabel_2 = uilabel(app.SampleHold_2);
            app.FsLabel_2.Text = 'Fs:       ';
            app.Fs_2 = uieditfield(app.SampleHold_2, 'numeric');
            app.Fs_2.Editable = 'off';
            app.AmplifierPanel_2 = uipanel(app.PipelineADCTab);
            app.AmplifierPanel_2.Title = 'Amplifier';
            app.Gain_S2 = uieditfield(app.AmplifierPanel_2, 'numeric');
            app.Gain_S2.Value = 8;
            app.Gain_S3 = uieditfield(app.AmplifierPanel_2, 'numeric');
            app.Gain_S3.Value = 8;
            app.Gain_S1 = uieditfield(app.AmplifierPanel_2, 'numeric');
            app.Gain_S1.Value = 8;
            app.GainLabel_2 = uilabel(app.AmplifierPanel_2);
            app.GainLabel_2.Text = 'Gain:';
            app.NLLabel = uilabel(app.AmplifierPanel_2);
            app.NLLabel.Text = 'NL(%):';
            app.NL_S1 = uieditfield(app.AmplifierPanel_2, 'numeric');
            app.NL_S2 = uieditfield(app.AmplifierPanel_2, 'numeric');
            app.NL_S3 = uieditfield(app.AmplifierPanel_2, 'numeric');
            app.FFTLabel = uilabel(app.AmplifierPanel_2);
            app.FFTLabel.Text = 'FFT:';
            app.AmpFFT_S1 = uicheckbox(app.AmplifierPanel_2);
            app.AmpFFT_S1.Text = '';
            app.AmpFFT_S2 = uicheckbox(app.AmplifierPanel_2);
            app.AmpFFT_S2.Text = '';
            app.AmpFFT_S3 = uicheckbox(app.AmplifierPanel_2);
            app.AmpFFT_S3.Text = '';
            app.PlotLabel = uilabel(app.AmplifierPanel_2);
            app.PlotLabel.Text = 'Plot:';
            app.AmpPlot_S1 = uicheckbox(app.AmplifierPanel_2);
            app.AmpPlot_S1.Text = '';
            app.AmpPlot_S2 = uicheckbox(app.AmplifierPanel_2);
            app.AmpPlot_S2.Text = '';
            app.AmpPlot_S3 = uicheckbox(app.AmplifierPanel_2);
            app.AmpPlot_S3.Text = '';
            app.S1S2S3Label_2 = uilabel(app.AmplifierPanel_2);
            app.S1S2S3Label_2.Text = 'S1   S2    S3';
            app.PredictorPanel_2 = uipanel(app.PipelineADCTab);
            app.PredictorPanel_2.Title = 'Predictor';
            app.TypeLabel_2 = uilabel(app.PredictorPanel_2);
            app.TypeLabel_2.Text = 'Type:   ';
            app.PredType_2 = uidropdown(app.PredictorPanel_2);
            app.PredType_2.Items = {'Real pole-Real zero', 'Real pole-Complex zero', 'Complex pole-Complex zero'};
            app.PredType_2.Value = 'Real pole-Complex zero';
            app.Pole_S2 = uieditfield(app.PredictorPanel_2, 'numeric');
            app.Pole_S3 = uieditfield(app.PredictorPanel_2, 'numeric');
            app.Pole_S1 = uieditfield(app.PredictorPanel_2, 'numeric');
            app.PoleLabel_2 = uilabel(app.PredictorPanel_2);
            app.PoleLabel_2.Text = 'Pole:';
            app.ZeroLabel_2 = uilabel(app.PredictorPanel_2);
            app.ZeroLabel_2.Text = 'Zero:';
            app.Zero_S1 = uieditfield(app.PredictorPanel_2, 'numeric');
            app.Zero_S1.Value = 1;
            app.Zero_S2 = uieditfield(app.PredictorPanel_2, 'numeric');
            app.Zero_S2.Value = 1;
            app.Zero_S3 = uieditfield(app.PredictorPanel_2, 'numeric');
            app.Zero_S3.Value = 1;
            app.Pred_Order_S2 = uieditfield(app.PredictorPanel_2, 'numeric');
            app.Pred_Order_S2.Value = 2;
            app.Pred_Order_S3 = uieditfield(app.PredictorPanel_2, 'numeric');
            app.Pred_Order_S3.Value = 2;
            app.Pred_Order_S1 = uieditfield(app.PredictorPanel_2, 'numeric');
            app.Pred_Order_S1.Value = 2;
            app.OrderLabel_5 = uilabel(app.PredictorPanel_2);
            app.OrderLabel_5.Text = 'Order';
            app.Ang_Ratio_S2 = uieditfield(app.PredictorPanel_2, 'numeric');
            app.Ang_Ratio_S2.Value = 1;
            app.Ang_Ratio_S3 = uieditfield(app.PredictorPanel_2, 'numeric');
            app.Ang_Ratio_S3.Value = 1;
            app.Ang_Ratio_S1 = uieditfield(app.PredictorPanel_2, 'numeric');
            app.Ang_Ratio_S1.Value = 1;
            app.RatioLabel = uilabel(app.PredictorPanel_2);
            app.RatioLabel.Text = '  Ratio:';
            app.Angle_S2 = uieditfield(app.PredictorPanel_2, 'numeric');
            app.Angle_S2.Editable = 'off';
            app.Angle_S3 = uieditfield(app.PredictorPanel_2, 'numeric');
            app.Angle_S3.Editable = 'off';
            app.Angle_S1 = uieditfield(app.PredictorPanel_2, 'numeric');
            app.Angle_S1.Editable = 'off';
            app.AngleLabel_2 = uilabel(app.PredictorPanel_2);
            app.AngleLabel_2.Text = 'Angle:';
            app.S1S2S3Label_6 = uilabel(app.PredictorPanel_2);
            app.S1S2S3Label_6.Text = 'S1   S2    S3';
            app.PlotLabel_5 = uilabel(app.PredictorPanel_2);
            app.PlotLabel_5.Text = 'Plot:';
            app.Pred_Plot_S1 = uicheckbox(app.PredictorPanel_2);
            app.Pred_Plot_S1.Text = '';
            app.Pred_Plot_S2 = uicheckbox(app.PredictorPanel_2);
            app.Pred_Plot_S2.Text = '';
            app.Pred_Plot_S3 = uicheckbox(app.PredictorPanel_2);
            app.Pred_Plot_S3.Text = '';
            app.FilterSpecsPanel_2 = uipanel(app.PipelineADCTab);
            app.FilterSpecsPanel_2.Title = 'Filter Specs';
            app.Term2_S2 = uieditfield(app.FilterSpecsPanel_2, 'numeric');
            app.Term2_S2.Editable = 'off';
            app.Term2_S3 = uieditfield(app.FilterSpecsPanel_2, 'numeric');
            app.Term2_S3.Editable = 'off';
            app.Term2_S1 = uieditfield(app.FilterSpecsPanel_2, 'numeric');
            app.Term2_S1.Editable = 'off';
            app.Term2Label_2 = uilabel(app.FilterSpecsPanel_2);
            app.Term2Label_2.Text = 'Term2:';
            app.Term3_S2 = uieditfield(app.FilterSpecsPanel_2, 'numeric');
            app.Term3_S2.Editable = 'off';
            app.Term3_S3 = uieditfield(app.FilterSpecsPanel_2, 'numeric');
            app.Term3_S3.Editable = 'off';
            app.Term3_S1 = uieditfield(app.FilterSpecsPanel_2, 'numeric');
            app.Term3_S1.Editable = 'off';
            app.Term3Label_2 = uilabel(app.FilterSpecsPanel_2);
            app.Term3Label_2.Text = 'Term3:';
            app.CreateFilterButton_2 = uibutton(app.PipelineADCTab, 'push');
            app.CreateFilterButton_2.ButtonPushedFcn = createCallbackFcn(app, @CreateFilterButton_2Pushed, true);
            app.CreateFilterButton_2.Text = 'Create Filter';
            app.DecimatorPanel_2 = uipanel(app.PipelineADCTab);
            app.DecimatorPanel_2.Title = 'Decimator';
            app.BWLabel_2 = uilabel(app.DecimatorPanel_2);
            app.BWLabel_2.Text = 'BW:            ';
            app.BW_2 = uieditfield(app.DecimatorPanel_2, 'numeric');
            app.BW_2.Editable = 'off';
            app.TypeDropDown_3Label_2 = uilabel(app.DecimatorPanel_2);
            app.TypeDropDown_3Label_2.Text = 'Type:';
            app.LPFType_2 = uidropdown(app.DecimatorPanel_2);
            app.LPFType_2.Items = {'FIR', 'Butterworth', 'Chebyshev I', 'Chebyshev II', ''};
            app.LPFType_2.Value = 'Butterworth';
            app.OrderLabel_4 = uilabel(app.DecimatorPanel_2);
            app.OrderLabel_4.Text = 'Order:       ';
            app.OrderLPF_2 = uieditfield(app.DecimatorPanel_2, 'numeric');
            app.OrderLPF_2.Value = 2;
            app.ADCfig_2 = uiimage(app.PipelineADCTab);
            app.ADCfig_2.ImageSource = 'GUI_Pipeline.PNG';
            app.OutputPanel_2 = uipanel(app.PipelineADCTab);
            app.OutputPanel_2.Title = 'Output';
            app.ENOBBitLabel_3 = uilabel(app.OutputPanel_2);
            app.ENOBBitLabel_3.Text = 'ENOB (Bit):  ';
            app.ENOBout_2 = uieditfield(app.OutputPanel_2, 'numeric');
            app.ENOBout_2.Editable = 'off';
            app.PlotTimeDropDownLabel_2 = uilabel(app.OutputPanel_2);
            app.PlotTimeDropDownLabel_2.Text = 'Plot (Time): ';
            app.PlotTimeout_2 = uidropdown(app.OutputPanel_2);
            app.PlotTimeout_2.Items = {'None', 'Only Out', 'Out and Input', ''};
            app.PlotTimeout_2.Value = 'None';
            app.SNDRdBLabel_3 = uilabel(app.OutputPanel_2);
            app.SNDRdBLabel_3.Text = 'SNDR (dB):  ';
            app.SNDRout_2 = uieditfield(app.OutputPanel_2, 'numeric');
            app.SNDRout_2.Editable = 'off';
            app.PlotFFTout_2 = uicheckbox(app.OutputPanel_2);
            app.PlotFFTout_2.Text = 'Plot (FFT)';
            app.ENOB_tBitLabel_2 = uilabel(app.OutputPanel_2);
            app.ENOB_tBitLabel_2.Text = 'ENOB_t (Bit):';
            app.ENOB_t_out_2 = uieditfield(app.OutputPanel_2, 'numeric');
            app.ENOB_t_out_2.Editable = 'off';
            app.RunSimulation_2 = uibutton(app.PipelineADCTab, 'push');
            app.RunSimulation_2.ButtonPushedFcn = createCallbackFcn(app, @RunSimulation_2ButtonPushed, true);
            app.RunSimulation_2.Text = 'Run Simulation';
            app.comparison_2 = uicheckbox(app.PipelineADCTab);
            app.comparison_2.Text = 'Prediction vs Input';
            app.status_2 = uieditfield(app.PipelineADCTab, 'text');
            app.status_2.Editable = 'off';
            app.FilteredOutputPanel_2 = uipanel(app.PipelineADCTab);
            app.FilteredOutputPanel_2.Title = 'Filtered Output';
            app.ENOBBitLabel_4 = uilabel(app.FilteredOutputPanel_2);
            app.ENOBBitLabel_4.Text = 'ENOB (Bit):  ';
            app.ENOBoutf_2 = uieditfield(app.FilteredOutputPanel_2, 'numeric');
            app.ENOBoutf_2.Editable = 'off';
            app.PlotTimeDropDown_2Label_2 = uilabel(app.FilteredOutputPanel_2);
            app.PlotTimeDropDown_2Label_2.Text = 'Plot (Time): ';
            app.PlotTimeoutf_2 = uidropdown(app.FilteredOutputPanel_2);
            app.PlotTimeoutf_2.Items = {'None', 'Only Out', 'Out and Input', ''};
            app.PlotTimeoutf_2.Value = 'None';
            app.SNDRdBLabel_4 = uilabel(app.FilteredOutputPanel_2);
            app.SNDRdBLabel_4.Text = 'SNDR (dB):  ';
            app.SNDRoutf_2 = uieditfield(app.FilteredOutputPanel_2, 'numeric');
            app.SNDRoutf_2.Editable = 'off';
            app.PlotFFToutf_2 = uicheckbox(app.FilteredOutputPanel_2);
            app.PlotFFToutf_2.Text = 'Plot (FFT)';
            app.Input_3 = uipanel(app.PipelineADCTab);
            app.Input_3.Title = 'Pipeline ';
            app.ofstagesLabel = uilabel(app.Input_3);
            app.ofstagesLabel.Text = '# of stages:';
            app.Num_Stage = uidropdown(app.Input_3);
            app.Num_Stage.Items = {'1', '2', '3'};
            app.Num_Stage.ValueChangedFcn = createCallbackFcn(app, @Num_StageValueChanged, true);
            app.Num_Stage.Value = '1';
            app.CLPTS_S1 = uicheckbox(app.Input_3);
            app.CLPTS_S1.Text = '';
            app.CLPTSLabel = uilabel(app.Input_3);
            app.CLPTSLabel.Text = 'CLPTS:';
            app.CLPTS_S2 = uicheckbox(app.Input_3);
            app.CLPTS_S2.Text = '';
            app.CLPTS_S3 = uicheckbox(app.Input_3);
            app.CLPTS_S3.Text = '';
            app.S1S2S3Label = uilabel(app.Input_3);
            app.S1S2S3Label.Text = 'S1   S2    S3';
            app.Compensate_S1 = uicheckbox(app.Input_3);
            app.Compensate_S1.Text = '';
            app.CompensLabel = uilabel(app.Input_3);
            app.CompensLabel.Text = 'Compens:';
            app.Compensate_S2 = uicheckbox(app.Input_3);
            app.Compensate_S2.Text = '';
            app.FF_S1 = uicheckbox(app.Input_3);
            app.FF_S1.Text = '';
            app.FFLabel = uilabel(app.Input_3);
            app.FFLabel.Text = 'FF:';
            app.FF_S2 = uicheckbox(app.Input_3);
            app.FF_S2.Text = '';
            app.IFLabel = uilabel(app.Input_3);
            app.IFLabel.Text = 'IF:';
            app.IF_S1 = uicheckbox(app.Input_3);
            app.IF_S1.Text = '';
            app.IF_S2 = uicheckbox(app.Input_3);
            app.IF_S2.Text = '';
            app.MethodLabel = uilabel(app.Input_3);
            app.MethodLabel.Text = 'Method:';
            app.Pipe_Method = uidropdown(app.Input_3);
            app.Pipe_Method.Items = {'Custome', 'Conventional', 'FF & IF', 'FF & Est_IF', 'Est_FF & IF', 'Err Compensation'};
            app.Pipe_Method.ValueChangedFcn = createCallbackFcn(app, @Pipe_MethodValueChanged, true);
            app.Pipe_Method.Value = 'Custome';
            app.SaturationPanel_2 = uipanel(app.PipelineADCTab);
            app.SaturationPanel_2.Title = 'Saturation';
            app.AnaSAT_S2 = uieditfield(app.SaturationPanel_2, 'numeric');
            app.AnaSAT_S2.Value = 1;
            app.AnaSAT_S3 = uieditfield(app.SaturationPanel_2, 'numeric');
            app.AnaSAT_S3.Value = 1;
            app.AnaSAT_S1 = uieditfield(app.SaturationPanel_2, 'numeric');
            app.AnaSAT_S1.Value = 1;
            app.A_LimLabel = uilabel(app.SaturationPanel_2);
            app.A_LimLabel.Text = 'A_Lim:';
            app.D_LimLabel = uilabel(app.SaturationPanel_2);
            app.D_LimLabel.Text = 'D_Lim:';
            app.DigSAT_S1 = uieditfield(app.SaturationPanel_2, 'numeric');
            app.DigSAT_S1.Editable = 'off';
            app.DigSAT_S2 = uieditfield(app.SaturationPanel_2, 'numeric');
            app.DigSAT_S2.Editable = 'off';
            app.DigSAT_S3 = uieditfield(app.SaturationPanel_2, 'numeric');
            app.DigSAT_S3.Editable = 'off';
            app.PlotLabel_2 = uilabel(app.SaturationPanel_2);
            app.PlotLabel_2.Text = 'Plot:';
            app.SATPlot_S1 = uicheckbox(app.SaturationPanel_2);
            app.SATPlot_S1.Text = '';
            app.SATPlot_S2 = uicheckbox(app.SaturationPanel_2);
            app.SATPlot_S2.Text = '';
            app.SATPlot_S3 = uicheckbox(app.SaturationPanel_2);
            app.SATPlot_S3.Text = '';
            app.S1S2S3Label_3 = uilabel(app.SaturationPanel_2);
            app.S1S2S3Label_3.Text = 'S1   S2    S3';
            app.ADCPanel_3 = uipanel(app.PipelineADCTab);
            app.ADCPanel_3.Title = 'ADC';
            app.ADCRes_S2 = uieditfield(app.ADCPanel_3, 'numeric');
            app.ADCRes_S2.Value = 3;
            app.ADCRes_S3 = uieditfield(app.ADCPanel_3, 'numeric');
            app.ADCRes_S3.Value = 3;
            app.ADCRes_S1 = uieditfield(app.ADCPanel_3, 'numeric');
            app.ADCRes_S1.Value = 6;
            app.ResBitLabel = uilabel(app.ADCPanel_3);
            app.ResBitLabel.Text = 'Res(Bit):';
            app.INLLSBLabel = uilabel(app.ADCPanel_3);
            app.INLLSBLabel.Text = 'INL(LSB):';
            app.ADCINL_S1 = uieditfield(app.ADCPanel_3, 'numeric');
            app.ADCINL_S2 = uieditfield(app.ADCPanel_3, 'numeric');
            app.ADCINL_S3 = uieditfield(app.ADCPanel_3, 'numeric');
            app.PlotLabel_3 = uilabel(app.ADCPanel_3);
            app.PlotLabel_3.Text = 'Plot:';
            app.ADCPlot_S1 = uicheckbox(app.ADCPanel_3);
            app.ADCPlot_S1.Text = '';
            app.ADCPlot_S2 = uicheckbox(app.ADCPanel_3);
            app.ADCPlot_S2.Text = '';
            app.ADCPlot_S3 = uicheckbox(app.ADCPanel_3);
            app.ADCPlot_S3.Text = '';
            app.S1S2S3Label_4 = uilabel(app.ADCPanel_3);
            app.S1S2S3Label_4.Text = 'S1   S2    S3';
            app.ADCG_Err_S2 = uieditfield(app.ADCPanel_3, 'numeric');
            app.ADCG_Err_S3 = uieditfield(app.ADCPanel_3, 'numeric');
            app.ADCG_Err_S1 = uieditfield(app.ADCPanel_3, 'numeric');
            app.G_ErrLabel = uilabel(app.ADCPanel_3);
            app.G_ErrLabel.Text = 'G_Err:';
            app.ADC_OfsErr_S2 = uieditfield(app.ADCPanel_3, 'numeric');
            app.ADC_OfsErr_S3 = uieditfield(app.ADCPanel_3, 'numeric');
            app.ADC_OfsErr_S1 = uieditfield(app.ADCPanel_3, 'numeric');
            app.Ofs_ErrLabel = uilabel(app.ADCPanel_3);
            app.Ofs_ErrLabel.Text = 'Ofs_Err:';
            app.DACPanel_3 = uipanel(app.PipelineADCTab);
            app.DACPanel_3.Title = 'DAC';
            app.DACRes_S2 = uieditfield(app.DACPanel_3, 'numeric');
            app.DACRes_S2.Value = 5;
            app.DACRes_S3 = uieditfield(app.DACPanel_3, 'numeric');
            app.DACRes_S3.Value = 5;
            app.DACRes_S1 = uieditfield(app.DACPanel_3, 'numeric');
            app.DACRes_S1.Value = 5;
            app.ResBitLabel_2 = uilabel(app.DACPanel_3);
            app.ResBitLabel_2.Text = 'Res(Bit):';
            app.INLLSBLabel_2 = uilabel(app.DACPanel_3);
            app.INLLSBLabel_2.Text = 'INL(LSB):';
            app.DACINL_S1 = uieditfield(app.DACPanel_3, 'numeric');
            app.DACINL_S2 = uieditfield(app.DACPanel_3, 'numeric');
            app.DACINL_S3 = uieditfield(app.DACPanel_3, 'numeric');
            app.PlotLabel_4 = uilabel(app.DACPanel_3);
            app.PlotLabel_4.Text = 'Plot:';
            app.DACPlot_S1 = uicheckbox(app.DACPanel_3);
            app.DACPlot_S1.Text = '';
            app.DACPlot_S2 = uicheckbox(app.DACPanel_3);
            app.DACPlot_S2.Text = '';
            app.DACPlot_S3 = uicheckbox(app.DACPanel_3);
            app.DACPlot_S3.Text = '';
            app.S1S2S3Label_5 = uilabel(app.DACPanel_3);
            app.S1S2S3Label_5.Text = 'S1   S2    S3';
            app.M0_S2 = uieditfield(app.DACPanel_3, 'numeric');
            app.M0_S2.Editable = 'off';
            app.M0_S3 = uieditfield(app.DACPanel_3, 'numeric');
            app.M0_S3.Editable = 'off';
            app.M0_S1 = uieditfield(app.DACPanel_3, 'numeric');
            app.M0_S1.Editable = 'off';
            app.M0BitLabel_3 = uilabel(app.DACPanel_3);
            app.M0BitLabel_3.Text = 'M0(Bit):';
            app.DoC_S2 = uieditfield(app.DACPanel_3, 'numeric');
            app.DoC_S2.Editable = 'off';
            app.DoC_S3 = uieditfield(app.DACPanel_3, 'numeric');
            app.DoC_S3.Editable = 'off';
            app.DoC_S1 = uieditfield(app.DACPanel_3, 'numeric');
            app.DoC_S1.Editable = 'off';
            app.DoCLabel_3 = uilabel(app.DACPanel_3);
            app.DoCLabel_3.Text = 'DoC:';
            app.StageLabel = uilabel(app.PipelineADCTab);
            app.StageLabel.Text = 'Stage:';
            app.Filter_Plot_Stage = uidropdown(app.PipelineADCTab);
            app.Filter_Plot_Stage.Items = {'1', '2'};
            app.Filter_Plot_Stage.ValueChangedFcn = createCallbackFcn(app, @Filter_Plot_StageValueChanged, true);
            app.Filter_Plot_Stage.Value = '1';
            app.ForwardandInverseFilterPanel = uipanel(app.PipelineADCTab);
            app.ForwardandInverseFilterPanel.Title = 'Forward and Inverse Filter';
            app.TypeDropDown_3Label_3 = uilabel(app.ForwardandInverseFilterPanel);
            app.TypeDropDown_3Label_3.Text = 'Type:';
            app.FF_Type = uidropdown(app.ForwardandInverseFilterPanel);
            app.FF_Type.Items = {'FIR', 'Butterworth', 'Chebyshev I', 'Chebyshev II', ''};
            app.FF_Type.Value = 'Butterworth';
            app.OrderLabel_6 = uilabel(app.ForwardandInverseFilterPanel);
            app.OrderLabel_6.Text = 'Order:       ';
            app.FF_Order_S1 = uieditfield(app.ForwardandInverseFilterPanel, 'numeric');
            app.FF_Order_S1.Value = 4;
            app.FF_Order_S2 = uieditfield(app.ForwardandInverseFilterPanel, 'numeric');
            app.FF_Order_S2.Value = 2;
            app.BWLabel_3 = uilabel(app.ForwardandInverseFilterPanel);
            app.BWLabel_3.Text = 'BW:';
            app.FF_BW_S1 = uieditfield(app.ForwardandInverseFilterPanel, 'numeric');
            app.FF_BW_S1.Editable = 'off';
            app.FF_BW_S2 = uieditfield(app.ForwardandInverseFilterPanel, 'numeric');
            app.FF_BW_S2.Editable = 'off';
            app.Est_FFLabel = uilabel(app.ForwardandInverseFilterPanel);
            app.Est_FFLabel.Text = 'Est_FF:';
            app.Est_FF_S1 = uicheckbox(app.ForwardandInverseFilterPanel);
            app.Est_FF_S1.Text = '';
            app.Est_FF_S2 = uicheckbox(app.ForwardandInverseFilterPanel);
            app.Est_FF_S2.Text = '';
            app.Est_IFLabel = uilabel(app.ForwardandInverseFilterPanel);
            app.Est_IFLabel.Text = 'Est_IF:';
            app.Est_IF_S1 = uicheckbox(app.ForwardandInverseFilterPanel);
            app.Est_IF_S1.Text = '';
            app.Est_IF_S2 = uicheckbox(app.ForwardandInverseFilterPanel);
            app.Est_IF_S2.Text = '';
            app.FF_Lim_S2 = uieditfield(app.ForwardandInverseFilterPanel, 'numeric');
            app.FF_Lim_S2.Value = 0.1;
            app.LimitLabel = uilabel(app.ForwardandInverseFilterPanel);
            app.LimitLabel.Text = 'Limit:       ';
            app.FF_Lim_S1 = uieditfield(app.ForwardandInverseFilterPanel, 'numeric');
            app.FF_Lim_S1.Value = 0.1;
            app.FilterLabel = uilabel(app.PipelineADCTab);
            app.FilterLabel.Text = 'Filter:';
            app.Filter_Plot_type = uidropdown(app.PipelineADCTab);
            app.Filter_Plot_type.Items = {'FF', 'IF'};
            app.Filter_Plot_type.ValueChangedFcn = createCallbackFcn(app, @Filter_Plot_typeValueChanged, true);
            app.Filter_Plot_type.Value = 'FF';
            app.StageLabel_2 = uilabel(app.PipelineADCTab);
            app.StageLabel_2.Text = 'Stage:';
            app.Fig_Plot_Stage = uidropdown(app.PipelineADCTab);
            app.Fig_Plot_Stage.Items = {'1', '2', '3'};
            app.Fig_Plot_Stage.ValueChangedFcn = createCallbackFcn(app, @Fig_Plot_StageValueChanged, true);
            app.Fig_Plot_Stage.Value = '1';
            app.FilterLabel_2 = uilabel(app.PipelineADCTab);
            app.FilterLabel_2.Text = 'Filter:';
            app.Fig_Plot_type = uidropdown(app.PipelineADCTab);
            app.Fig_Plot_type.Items = {'Loop', 'Predictor', 'Decimator'};
            app.Fig_Plot_type.ValueChangedFcn = createCallbackFcn(app, @Fig_Plot_typeValueChanged, true);
            app.Fig_Plot_type.Value = 'Loop';
            % Show the figure after all components are created
            app.CLPTSADC.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Predictive_Two_step

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CLPTSADC)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CLPTSADC)
        end
    end
end